classdef Alt_Approach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NoisePanel                  matlab.ui.container.Panel
        GetNoiseButton              matlab.ui.control.Button
        STDmHzEditFieldLabel        matlab.ui.control.Label
        STDmHzEditField             matlab.ui.control.NumericEditField
        NoisemaxmHzEditFieldLabel   matlab.ui.control.Label
        NoisemaxmHzEditField        matlab.ui.control.NumericEditField
        Lamp                        matlab.ui.control.Lamp
        PokeSettingsPanel           matlab.ui.container.Panel
        TipSpeednmsSpinnerLabel     matlab.ui.control.Label
        TipSpeednmsSpinner          matlab.ui.control.Spinner
        sig_noSpinnerLabel          matlab.ui.control.Label
        sig_noSpinner               matlab.ui.control.Spinner
        RetractnmSpinnerLabel       matlab.ui.control.Label
        RetractnmSpinner            matlab.ui.control.Spinner
        UpdateThresholdButton       matlab.ui.control.Button
        AvgSpinnerLabel             matlab.ui.control.Label
        AvgSpinner                  matlab.ui.control.Spinner
        ThresholdmHzEditFieldLabel  matlab.ui.control.Label
        ThresholdmHzEditField       matlab.ui.control.NumericEditField
        TouchPointumEditFieldLabel  matlab.ui.control.Label
        TouchPointumEditField       matlab.ui.control.NumericEditField
        ControlPanel                matlab.ui.container.Panel
        BlinkButton                 matlab.ui.control.Button
        PokeButton                  matlab.ui.control.Button
        Lamp_2                      matlab.ui.control.Lamp
        StopButton                  matlab.ui.control.Button
        ZScannerumEditFieldLabel    matlab.ui.control.Label
        ZScannerumEditField         matlab.ui.control.NumericEditField
        TextArea                    matlab.ui.control.TextArea
        ApproachSettingsPanel       matlab.ui.container.Panel
        BlinkCheckBox               matlab.ui.control.CheckBox
        ZEncoderumEditFieldLabel    matlab.ui.control.Label
        ZEncoderumEditField         matlab.ui.control.NumericEditField
        nomotorstepsEditFieldLabel  matlab.ui.control.Label
        nomotorstepsEditField       matlab.ui.control.NumericEditField
        ApproachModeCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        Ctrl_index = 10;
        %fshift_ch_index = 34; % Fshift Index in the Data Logger module
        fshift_ch = 18; % Channel Index in the Data Logger module (Test!)
        mu_ch = 3; % Channel Index that holds the average (Output 3)
        blink_ch = 2; % Channel Index for DAC
        
        Acq_dur = 5; BaseName = "noise";
        
        % find a better directory to log the noise! so it will be automatic
        
       Session_Folder = [Nanonis.Get_Session_Folder(),'\']; 

        sigma; mu; noise_max; Threshold; Z_Pos; Stop = 0; fshift;
        retract = 200; tip_speed = 10; scanner_limit = 22; sig_no = 6; mu_avg = 200;
        PI_Const = 10^-6;
        
    end
    

    methods (Access = public)
        
        function disp_message(app,new_message)
            
            time_date = datestr(now);
            message = [time_date(end - 7:end),'   ',new_message];
            app.TextArea.Value = [message;app.TextArea.Value];
            
        end
        
        function get_noise(app)
            % Suppress an annoying warning
            warning('off','MATLAB:table:ModifiedAndSavedVarnames')
            Nanonis.OutputOn();
            Nanonis.PllOn();
            app.Lamp.Color = 'green';
            
            Nanonis.Uti_RTOversamplSet(1)
            
            app.disp_message('Noise Measurement Started')
            
            Nanonis.DataLog_Open()
            pause(0.2)
            Nanonis.DataLog_ChsSet(app.fshift_ch)
            pause(0.2)
            Nanonis.DataLog_PropsSet(app.BaseName, app.Acq_dur)
            Nanonis.DataLog_Start()
            pause(app.Acq_dur + 1)
            
            Nanonis.Uti_RTOversamplSet(10)
            
            % Importing file and calculating
            k = ceil(0.8*app.Acq_dur); % File can only hold 25,000 points, so this calculation is for how many files are needed
            A = [];
            currentDate = date;
            for i=1:k
%                 file_name = strcat(app.Session_Folder, app.BaseName, "00",num2str(i),".dat");
                file_name = strcat(app.Session_Folder(1:end-11),currentDate(4:6),'\',...
                    currentDate(1:2),'\SXM\', app.BaseName, "00",num2str(i),".dat");

                ifA = [A; table2array(readtable(file_name))];
                delete(file_name)
            end
            
            app.sigma = std(A); app.mu = mean(A);
            B = A - ones(length(A),1)*app.mu;
            app.noise_max = max(abs(B));
            
            % Updating Fields
            app.STDmHzEditField.Value = round(app.sigma*1000);
            app.NoisemaxmHzEditField.Value = round(app.noise_max*1000);
            
            % Updating Threshold
            app.Threshold = app.sigma*app.sig_no; % Calculating
            app.ThresholdmHzEditField.Value = app.Threshold*1000; % Updating Field
            Nanonis.SafeTip_SetThreshold(app.Threshold) % Setting Threshold
            
            % Updating Average
            Nanonis.Set(app.mu_ch,app.mu)
            if app.sigma>25e-3
                app.disp_message('Noise Level is high, Try reducing LPF cut-off frequency')
            end
            
            app.disp_message('Noise Measurement Ended')
            app.Lamp.Color = 'white';
            
        end
        
        function set_ZCtrl(app)
            
            Nanonis.ZCtrl_SetCtrl(app.Ctrl_index) % Choosing specific controller
            Nanonis.ZCtrl_SetOnOff(0) % Making sure controller is off
            
            Nanonis.ZCtrl_SetPoint(app.PI_Const) % Sets Setpoint 
            % (should be same value as time constant and very small, as it may cause a jump at the start of the approach)
            Nanonis.ZCtrl_SetGain(app.tip_speed*10^-9, app.PI_Const, 0) % Tip Speed and time constant (same as setpoint)
            Nanonis.SafeTip_SetOnOff(2) % Turning SafeTip Off
            Nanonis.ZCtrl_SetHome(app.retract*10^-9) % Setting home relative and retract amount
            
        end
        
        function construct_mu(app)
            
            app.fshift = zeros(app.mu_avg,1);
            for i=1:app.mu_avg
                app.fshift(i) = Nanonis.Getfshift();
%                 app.fshift(i) = Nanonis.Get(18); %Test!
                pause(0.01)
            end
            app.mu = mean(app.fshift);
            Nanonis.Set(app.mu_ch,app.mu)
            
        end
        
        function update_mu(app)
            
            app.fshift = circshift(app.fshift,-3);
            for i=1:3
                app.fshift(end + i - 3) = Nanonis.Getfshift();
%                 app.fshift(end + i - 3) = Nanonis.Get(18); % For Test Only!
                pause(0.01)
            end
            app.mu = mean(app.fshift);
            Nanonis.Set(app.mu_ch,app.mu)
            
        end
        
        function poke(app)
            
            app.ZEncoderumEditField.Value = Nanonis.Get_Encoder_Z();
            app.Lamp_2.Color = 'yellow';
            app.disp_message('Poke Initiated')
            app.set_ZCtrl() % Reseting Z-Controller
            Nanonis.OutputOn();
            Nanonis.PllOn();           
            app.construct_mu() % Initial mu calculation
            

            pause(3);
            Nanonis.SafeTip_SetOnOff(1) % SafeTip On
            
            Nanonis.ZCtrl_SetOnOff(1) % Turning Z-Controller On
            app.disp_message('Z-Controller On')
            app.Lamp_2.Color = 'green';
            pause(0.1)
            
            % Main Loop
            
            while 1
                drawnow
                
                if app.Stop % Stop Button Pressed
                    Nanonis.ZCtrl_SetOnOff(0)
                    Nanonis.Set_Scanner_Z(Nanonis.Get_Scanner_Z - app.retract*10^-3)
                    
                    app.disp_message('Poke Stopped')
                    app.Stop = 0;
                    app.TouchPointumEditField.Value = 0;
                    break
                end
                
                if Nanonis.Get_Scanner_Z() >= app.scanner_limit % Scanner Limit Reached
                    Nanonis.ZCtrl_SetOnOff(0)
                    app.TouchPointumEditField.Value = 0;
                    
                    % The following if condition is for the different modes
                    
                    if app.ApproachModeCheckBox.Value % "Approach" mode
                        app.disp_message('Scanner Limit reached. Withdrawing Tip')
                        Nanonis.ZCtrl_Withdraw()
                        pause(1)
                        Nanonis.Motors_Move_Steps(app.nomotorstepsEditField.Value,'z+')
                        pause(1)
                        message = strcat('Z Motor Porpogated. Encoder Value: ', num2str(Nanonis.Get_Encoder_Z()));
                        app.disp_message(message)
                        
                        if app.BlinkCheckBox.Value % Blinking
                            DAC.Blink(app.blink_ch)
                            pause(1)
                            app.disp_message('Blink Performed')
                        end
                        
                        app.poke() % recursive
                        
                    else % "Poke" mode
                        app.disp_message('Scanner Limit reached. Retracting Tip')
                        Nanonis.Set_Scanner_Z(Nanonis.Get_Scanner_Z - app.retract*10^-3)
                    end
                    
                    break
                end
                
                if ~Nanonis.ZCtrl_GetOnOff() % ZCtrl is Off (Assuming SafeTip Triggered)
                    pause(0.2)
                    app.TouchPointumEditField.Value = double(Nanonis.Get_Scanner_Z()) + app.retract*10^-3; % in um;
                    app.disp_message(char(strcat('SafeTip Triggered, touch point=',string(app.TouchPointumEditField.Value))))
                    General.Beep();
                    break
                end
                app.update_mu() % Updating Average
                app.ZScannerumEditField.Value = round(double(Nanonis.Get_Scanner_Z()),3); % Field
                
            end
            
            app.ZScannerumEditField.Value = round(double(Nanonis.Get_Scanner_Z()),3);
            app.Lamp_2.Color = 'white';
            Nanonis.SafeTip_SetOnOff(2); %turn off safe tip

            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Value = 'Welcome to the new Approach app. Hope you like it!';
            % Deleting Files
            k = ceil(0.8*app.Acq_dur);
            for i=1:k
                file_name = strcat(app.Session_Folder, app.BaseName, "00",num2str(i),".dat");
                if isfile(file_name)
                    delete(file_name)
                end
            end
            % Z-Encoder
t             app.ZEncoderumEditField.Value = Nanonis.Get_Encoder_Z();
        end

        % Value changed function: TipSpeednmsSpinner
        function TipSpeednmsSpinnerValueChanged(app, event)
            app.tip_speed = app.TipSpeednmsSpinner.Value;
            Nanonis.ZCtrl_SetGain(app.tip_speed*10^-9, app.PI_Const, 0)
        end

        % Value changed function: RetractnmSpinner
        function RetractnmSpinnerValueChanged(app, event)
            app.retract = app.RetractnmSpinner.Value;
            Nanonis.ZCtrl_SetHome(app.retract*10^-9)
        end

        % Button pushed function: GetNoiseButton
        function GetNoiseButtonPushed(app, event)
            app.get_noise()
        end

        % Button pushed function: PokeButton
        function PokeButtonPushed(app, event)
            app.poke()
            Nanonis.PllOff();
            Nanonis.OutputOff();
        end

        % Button pushed function: UpdateThresholdButton
        function UpdateThresholdButtonPushed(app, event)
            app.Threshold = app.sigma*app.sig_no; % Calculating Threshold
            app.ThresholdmHzEditField.Value = app.Threshold*1000; % Update Field
            Nanonis.SafeTip_SetThreshold(app.Threshold) % Setting Threshold
        end

        % Value changed function: sig_noSpinner
        function sig_noSpinnerValueChanged(app, event)
            app.sig_no = app.sig_noSpinner.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Stop = 1;
        end

        % Value changed function: AvgSpinner
        function AvgSpinnerValueChanged(app, event)
            app.mu_avg = app.AvgSpinner.Value;
        end

        % Button pushed function: BlinkButton
        function BlinkButtonPushed(app, event)
            DAC.Blink(app.blink_ch);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.set_ZCtrl()
            Nanonis.SafeTip_SetOnOff(2)
            
            delete(app)
            
        end

        % Value changed function: ApproachModeCheckBox
        function ApproachModeCheckBoxValueChanged(app, event)
            value = app.ApproachModeCheckBox.Value;
            if value
                app.PokeButton.Text = 'Approach';
            else
                app.PokeButton.Text = 'Poke';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NoisePanel = uipanel(app.UIFigure);
            app.NoisePanel.Title = 'Noise';
            app.GetNoiseButton = uibutton(app.NoisePanel, 'push');
            app.GetNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GetNoiseButtonPushed, true);
            app.GetNoiseButton.Text = 'Get Noise';
            app.STDmHzEditFieldLabel = uilabel(app.NoisePanel);
            app.STDmHzEditFieldLabel.Text = 'STD (mHz)';
            app.STDmHzEditField = uieditfield(app.NoisePanel, 'numeric');
            app.NoisemaxmHzEditFieldLabel = uilabel(app.NoisePanel);
            app.NoisemaxmHzEditFieldLabel.Text = 'Noise max (mHz)';
            app.NoisemaxmHzEditField = uieditfield(app.NoisePanel, 'numeric');
            app.Lamp = uilamp(app.NoisePanel);
            app.PokeSettingsPanel = uipanel(app.UIFigure);
            app.PokeSettingsPanel.Title = 'Poke Settings';
            app.TipSpeednmsSpinnerLabel = uilabel(app.PokeSettingsPanel);
            app.TipSpeednmsSpinnerLabel.Text = 'Tip Speed (nm/s)';
            app.TipSpeednmsSpinner = uispinner(app.PokeSettingsPanel);
            app.TipSpeednmsSpinner.Step = 5;
            app.TipSpeednmsSpinner.Limits = [5 200];
            app.TipSpeednmsSpinner.ValueChangedFcn = createCallbackFcn(app, @TipSpeednmsSpinnerValueChanged, true);
            app.TipSpeednmsSpinner.Value = 10;
            app.sig_noSpinnerLabel = uilabel(app.PokeSettingsPanel);
            app.sig_noSpinnerLabel.Text = 'sig_no';
            app.sig_noSpinner = uispinner(app.PokeSettingsPanel);
            app.sig_noSpinner.ValueChangedFcn = createCallbackFcn(app, @sig_noSpinnerValueChanged, true);
            app.sig_noSpinner.Value = 6;
            app.RetractnmSpinnerLabel = uilabel(app.PokeSettingsPanel);
            app.RetractnmSpinnerLabel.Text = 'Retract (nm)';
            app.RetractnmSpinner = uispinner(app.PokeSettingsPanel);
            app.RetractnmSpinner.Step = 50;
            app.RetractnmSpinner.Limits = [0 1000];
            app.RetractnmSpinner.ValueChangedFcn = createCallbackFcn(app, @RetractnmSpinnerValueChanged, true);
            app.RetractnmSpinner.Value = 200;
            app.UpdateThresholdButton = uibutton(app.PokeSettingsPanel, 'push');
            app.UpdateThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateThresholdButtonPushed, true);
            app.UpdateThresholdButton.Text = 'Update Threshold';
            app.AvgSpinnerLabel = uilabel(app.PokeSettingsPanel);
            app.AvgSpinnerLabel.Text = 'Avg';
            app.AvgSpinner = uispinner(app.PokeSettingsPanel);
            app.AvgSpinner.Step = 50;
            app.AvgSpinner.Limits = [50 500];
            app.AvgSpinner.ValueChangedFcn = createCallbackFcn(app, @AvgSpinnerValueChanged, true);
            app.AvgSpinner.Value = 200;
            app.ThresholdmHzEditFieldLabel = uilabel(app.PokeSettingsPanel);
            app.ThresholdmHzEditFieldLabel.Text = 'Threshold (mHz)';
            app.ThresholdmHzEditField = uieditfield(app.PokeSettingsPanel, 'numeric');
            app.TouchPointumEditFieldLabel = uilabel(app.PokeSettingsPanel);
            app.TouchPointumEditFieldLabel.Text = 'Touch Point (um)';
            app.TouchPointumEditField = uieditfield(app.PokeSettingsPanel, 'numeric');
            app.TouchPointumEditField.ValueDisplayFormat = '%.3f';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.BlinkButton = uibutton(app.ControlPanel, 'push');
            app.BlinkButton.ButtonPushedFcn = createCallbackFcn(app, @BlinkButtonPushed, true);
            app.BlinkButton.Text = 'Blink';
            app.PokeButton = uibutton(app.ControlPanel, 'push');
            app.PokeButton.ButtonPushedFcn = createCallbackFcn(app, @PokeButtonPushed, true);
            app.PokeButton.Text = 'Poke';
            app.Lamp_2 = uilamp(app.ControlPanel);
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ZScannerumEditFieldLabel = uilabel(app.ControlPanel);
            app.ZScannerumEditFieldLabel.Text = 'Z-Scanner(um)';
            app.ZScannerumEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ZScannerumEditField.ValueDisplayFormat = '%.3f';
            app.TextArea = uitextarea(app.UIFigure);
            app.ApproachSettingsPanel = uipanel(app.UIFigure);
            app.ApproachSettingsPanel.Title = 'Approach Settings';
            app.BlinkCheckBox = uicheckbox(app.ApproachSettingsPanel);
            app.BlinkCheckBox.Text = ' Blink';
            app.ZEncoderumEditFieldLabel = uilabel(app.ApproachSettingsPanel);
            app.ZEncoderumEditFieldLabel.Text = 'Z-Encoder (um)';
            app.ZEncoderumEditField = uieditfield(app.ApproachSettingsPanel, 'numeric');
            app.nomotorstepsEditFieldLabel = uilabel(app.ApproachSettingsPanel);
            app.nomotorstepsEditFieldLabel.Text = 'no. motor steps';
            app.nomotorstepsEditField = uieditfield(app.ApproachSettingsPanel, 'numeric');
            app.nomotorstepsEditField.Value = 400;
            app.ApproachModeCheckBox = uicheckbox(app.ApproachSettingsPanel);
            app.ApproachModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ApproachModeCheckBoxValueChanged, true);
            app.ApproachModeCheckBox.Text = ' Approach Mode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Alt_Approach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end