classdef Approach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ApproachTab                  matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        AC_Channel_Text              matlab.ui.control.Label
        AC_Channel_Box               matlab.ui.control.EditField
        Frame_Fal_Text               matlab.ui.control.Label
        DC_Channel_Text              matlab.ui.control.Label
        DC_Channel_Box               matlab.ui.control.EditField
        Frame_Val_Box                matlab.ui.control.EditField
        Retract_Button               matlab.ui.control.Button
        AppruchStatus_Text1          matlab.ui.control.Label
        Zs_ext_Slider                matlab.ui.control.Slider
        Zenc_Steper_Text             matlab.ui.control.Label
        Zenc_Steper_Box              matlab.ui.control.EditField
        Zs_PauseStep_Text            matlab.ui.control.Label
        Zenc_PauseStep_Box           matlab.ui.control.EditField
        Start_Stop_Text              matlab.ui.control.Label
        text32                       matlab.ui.control.Label
        DataPerdZ                    matlab.ui.control.EditField
        Start_Stop_Button            matlab.ui.control.StateButton
        ClearplotButton              matlab.ui.control.Button
        Blink                        matlab.ui.control.CheckBox
        Blink_Chan                   matlab.ui.control.NumericEditField
        Retract_Condition            matlab.ui.control.CheckBox
        ZencodedmaxpositionumLabel   matlab.ui.control.Label
        Zencoded_Max_Box             matlab.ui.control.EditField
        MessagesTextAreaLabel        matlab.ui.control.Label
        MessagesTextArea             matlab.ui.control.TextArea
        ACupperlimitVLabel           matlab.ui.control.Label
        ACp_Box                      matlab.ui.control.NumericEditField
        AClowerlimitVEditFieldLabel  matlab.ui.control.Label
        ACn_Box                      matlab.ui.control.NumericEditField
        DCupperlimitVEditFieldLabel  matlab.ui.control.Label
        DCp_Box                      matlab.ui.control.NumericEditField
        DClowerlimitVEditFieldLabel  matlab.ui.control.Label
        DCn_Box                      matlab.ui.control.NumericEditField
        ZscannervalumLabel           matlab.ui.control.Label
        Zs_Val_Box                   matlab.ui.control.NumericEditField
        ZscannerextentionlimitumEditFieldLabel  matlab.ui.control.Label
        Zs_Ext_Box                   matlab.ui.control.NumericEditField
        ZsstepumLabel                matlab.ui.control.Label
        Zs_Step_Box                  matlab.ui.control.NumericEditField
        ThermalRatioLabel            matlab.ui.control.Label
        Ratio_Box                    matlab.ui.control.NumericEditField
        ThermalInitialLabel          matlab.ui.control.Label
        Thermal_Initial              matlab.ui.control.NumericEditField
        YoffsetLabel                 matlab.ui.control.Label
        ScaleY                       matlab.ui.control.NumericEditField
        Retract_Condition_Val        matlab.ui.control.NumericEditField
        umLabel                      matlab.ui.control.Label
        GetZscanner                  matlab.ui.control.Button
        Update_Limits                matlab.ui.control.Button
        Approach_Kind                matlab.ui.container.ButtonGroup
        Thermal                      matlab.ui.control.RadioButton
        TF                           matlab.ui.control.RadioButton
        Other                        matlab.ui.control.RadioButton
        ChanLabel                    matlab.ui.control.Label
        BlinkButton                  matlab.ui.control.Button
        TFAvrEditFieldLabel          matlab.ui.control.Label
        TFAvr                        matlab.ui.control.NumericEditField
        TFSigmaLabel                 matlab.ui.control.Label
        TF_Sigma_Factor              matlab.ui.control.NumericEditField
        GetZencoderButton            matlab.ui.control.Button
        StopTimerButton              matlab.ui.control.StateButton
        Plus_Zscanner                matlab.ui.control.Button
        Minus_Zscanner               matlab.ui.control.Button
        stepsizeumLabel              matlab.ui.control.Label
        Plus_Minus_Zscanner_Step     matlab.ui.control.NumericEditField
        Z_Motor_axis2                matlab.ui.control.UIAxes
        DC_Axes                      matlab.ui.control.UIAxes
        AC_Axes                      matlab.ui.control.UIAxes
        Z_Motor_axis                 matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    properties (Access = public)
        
    end
    
    properties (Access = private)
        ACx=0 % Description
        ACy=0 % Description
        DCx=0 % Description
        DCy=0 % Description
        Zpx=1; % Description
        Zpy % Description
        Scale_X=0.1 % Description
        ACp % Description
        ACn % Description
        DCp % Description
        DCn % Description
        Frame_Val % Description
        DC_Animated % Description
        AC_Animated % Description
        Zp_Animated % Description
        Zp_Animated2 % Description
        Zscanner_Val=0 % Description
        Zs_Limit=22 % Description  % Take from Nanonis...
        Zs_Extension=22 % Description
        Run_Condition=0 % Need to be true for the software to run
        AC_Channel; % Description
        DC_Channel; % Description
        Zs_Step % Description
        Timer % Description
        Step_Average % Description
        Thermal_c % Description
        Thermal_Ratio % Description
        Encoder_Vec % Description
        Ratio_Vec % Description
        TF_Freqshift_Vec=zeros(1,103) % Description
        TF_Mu % Description
        TF_Sigma % Description
    end
    
    methods (Access = private)
        
        function  Plot_ACDC(app)

            % Plot AC
            if  app.ACx>app.Frame_Val
                app.ClearplotButtonPushed(app)
            end

            % Plot AC
            app.ACx=app.ACx+1;   % Fix time

            switch app.Approach_Kind.SelectedObject.Text
                case 'Thermal'

                      % Average?
%                       if toc(app.tictoc) > (1/10)
%                              channels = double(Nanonis.Gets([app.AC_Channel,app.DC_Channel]));
%                              app.ACy = channels(1);
%                               app.DCy = channels(2);
%                
%                      end 
                     channels = double(Nanonis.Gets([app.AC_Channel,app.DC_Channel]));
                     app.ACy = channels(1);
                     app.DCy = channels(2);
                case 'TF'

                      % Average?
                    app.ACy=double(Nanonis.Getfshift());

                    % update the limits
                    app.TF_Freqshift_Vec=circshift(app.TF_Freqshift_Vec,-1);
                    app.TF_Freqshift_Vec(end)=app.ACy;
                    app.TF_Mu=mean(app.TF_Freqshift_Vec(end-app.TFAvr.Value:end-2));

                    app.ACp_Box.Value=double(app.TF_Mu+app.TF_Sigma_Factor.Value*app.TF_Sigma);
                    app.ACn_Box.Value=double(app.TF_Mu-app.TF_Sigma_Factor.Value*app.TF_Sigma);

                    try
                        app.ACp_BoxValueChanged(app)
                        app.ACn_BoxValueChanged(app)
                    catch er
                        disp(er.message)
                    end
                                        % UD_Axis_Limit(app)
                case 'Other'
                    app.ACy=double(Nanonis.Get(app.AC_Channel));
            end
            % Plot DC

%             app.DCy=double(Nanonis.Get(app.DC_Channel));
%             tic

            % app.test_x(end+1) = app.ACx;
            % app.test_y(end+1) = app.ACy;
            % app.test_y2(end+1) = app.DCy;
            % 
%             if toc(app.tictoc) > (1/10)
%                 addpoints(app.AC_Animated,app.test_x,app.test_y);
%                 addpoints(app.DC_Animated,app.test_x,app.test_y2);
%                 app.test_x = [];
%                 app.test_y = [];
%                 app.test_y2 = [];
%             
%                 app.tictoc = tic;
%             end

            addpoints(app.AC_Animated,app.ACx,app.ACy);
            addpoints(app.DC_Animated,app.ACx,app.DCy);

%             toc
        end
        
        function Plot_ZpExe(app)
            % Plots by demends
            
            %X is The previus readout of Z scanner, Y is the thermal value
            
            switch app.Approach_Kind.SelectedObject.Text
                case 'Thermal'
                    app.Ratio_Vec(end+1)=app.Thermal_Ratio;
                    app.Encoder_Vec(end+1)=app.Zpy;
                    addpoints(app.Zp_Animated2,app.Encoder_Vec(end),app.Ratio_Vec(end));
                case 'TF'
                    
                case 'Other'
                    
            end
            
            app.Zpx=app.Zpx+1;   % Fix time
            app.Zpy=Nanonis.Get_Encoder_Z();   % Value of Z scanner
            addpoints(app.Zp_Animated,app.Zpx,app.Zpy);
            drawnow
        end
        
        function UD_Axis_Limit(app)
            app.AC_Axes.YLim=[app.ACn_Box.Value app.ACp_Box.Value]+[-1 1].*app.ScaleY.Value;
            app.DC_Axes.YLim=[app.DCn_Box.Value app.DCp_Box.Value]+[-1 1].*app.ScaleY.Value;
        end
        
        function Set_Zs(app)
            
            % Cheack the limet of the extintion, if ok
            
            Con1=app.Zscanner_Val<=app.Zs_Limit; % Z scanner val is smaller then the limit
            if Con1
                % Sets the new value of Zs and update it value
                try
                
                    Nanonis.Set_Scanner_Z(app.Zscanner_Val);
                    pause(0.03)
                    % A solution to pause(0.03) which caused program
                    % runtime jumps due to OS issue
%                         a = tic;
%                         while toc(a) < 0.003
%                         end
                    app.Zscanner_Val=double(Nanonis.Get_Scanner_Z());
                    
                    % Updates the box and slider values
                    
                    
                    app.Zs_Val_Box.Value=round(app.Zscanner_Val,3);
                    
                    app.Zs_ext_Slider.Value=app.Zscanner_Val;
                    

                catch err
               
                    app.MessagesTextArea.Value = [err.message;app.MessagesTextArea.Value];
                end
             % If the value of Zs - step size is smaller then the limit
             % but Zs is larger then the limit - go to the limit
            elseif app.Zscanner_Val-app.Zs_Step_Box.Value<=app.Zs_Limit
               
               
                app.Zscanner_Val=app.Zs_Limit;
                Set_Zs(app);
            else
                
                app.Zscanner_Val=Nanonis.Get_Scanner_Z();
                
            end
           
        end
        
        function Timer_Fun(app,~,~)
       
            Plot_ACDC(app)
            if app.Run_Condition==1 % If ok to continiu in Z
                
                if  UD_Stop_Limit(app)  % If the value in threshold
                    
                    app.Zscanner_Val=app.Zscanner_Val+app.Zs_Step;
                    
                    Set_Zs(app)
                    
                    if app.Zscanner_Val==app.Zs_Extension
                    
                        switch app.Approach_Kind.SelectedObject.Text
                            case 'Thermal'
                                
                                app.ACy = double(Nanonis.Get(app.AC_Channel));
                                app.Thermal_Ratio=app.ACy/app.Thermal_Initial.Value;
                                % Message
                                d=datestr(datetime);
                                Message=[d(13:end),' Thermal ratio is: ',num2str(app.Thermal_Ratio)];
                                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
                                if app.Thermal_Ratio>app.Ratio_Box.Value
                                    
                                    d=datestr(datetime);
                                    Message=[d(13:end),' Thermal ratio condition reached, approach stoped!'];
                                    app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
                                    app.Retract_Button_Callback(app);
                    else 
                                    
                                    Propagate_Zmotor(app);
                                end
                                pause(1)
                     
                                app.Thermal_Initial.Value=double(Nanonis.Get_Average(app.AC_Channel,20));  % up date the thermal initial index
                                Thermal_InitialValueChanged(app);
                                
                            case 'TF'
                                Propagate_Zmotor(app);
                            case 'Other'
                                Propagate_Zmotor(app);
                                
                        end 
              
                    end 
                end 
            end
        end        
        
        function Con = UD_Stop_Limit(app)
            % Which condition
            Con=1;
            %             app.ACy=double(Nanonis.Get_Average(app.AC_Channel,app.Step_Average));
            Con1=app.ACy>app.ACp_Box.Value;
            Con2=app.ACy<app.ACn_Box.Value;
            Con3=app.DCy>app.DCp_Box.Value;
            Con4=app.DCy<app.DCn_Box.Value;
            
            if  Con1|| Con2||Con3||Con4
                
                % message
                d=datestr(datetime);
                
                switch  1
                    case Con1
                        Message=[d(13:end),' Threshold_AC+ = ',num2str(app.ACy),'[V], Z scan = ',num2str(app.Zscanner_Val),' [um]'];
                    case Con2
                        Message=[d(13:end),' Threshold_AC- = ',num2str(app.ACy),'[V], Z scan = ',num2str(app.Zscanner_Val),' [um]'];
                    case Con3
                        Message=[d(13:end),' Threshold_DC+ = ',num2str(app.DCy),'[V], Z scan = ',num2str(app.Zscanner_Val),' [um]'];
                    case Con4
                        Message=[d(13:end),' Threshold_DC- = ',num2str(app.DCy),'[V], Z scan = ',num2str(app.Zscanner_Val),' [um]'];
                end
                
                
                %   Retract if needed
                if app.Retract_Condition.Value
                    app.Zscanner_Val=app.Zscanner_Val-app.Retract_Condition_Val.Value;
                    Set_Zs(app);
                end
                
                % Stop moving
                if app.Start_Stop_Button.Value==1
                    app.Start_Stop_Button.Value=0;
                end
                Start_Stop_ButtonValueChanged(app);
                Con=0;
                
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];  % Add message
                General.Beep()
                
            end
        end
        
        function Propagate_Zmotor(app)
            
            pause(1)
            app.Zscanner_Val=0; % Retract
            Set_Zs(app);
            pause(1)
            
            if Nanonis.Get_Encoder_Z()<str2double(app.Zencoded_Max_Box.Value)
                
                Nanonis.Motors_Move_Steps(str2double(app.Zenc_Steper_Box.Value),'z+');
                pause(str2double(app.Zenc_PauseStep_Box.Value))
                
                % Message
                d=datestr(datetime);
                Message=[d(13:end),'  Z motor moved, new val: ', num2str(Nanonis.Get_Encoder_Z())];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
        else
               
                % Message
                d=datestr(datetime);
                Message= [d(13:end) ' Z motor is at the limit, motor didn''t move'];
                
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            end
            Plot_ZpExe(app)
            
            if app.Blink.Value   % Blink if wish to
                try
                    DAC.Blink(app.Blink_Chan.Value)
                catch
                    warning('no DAC')
                end
               pause(1)
            end
            
        end
        
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Approuch_OpeningFcn(app)
            % When opening the GUI set the variable to the default values
            
            %   Plot message
            d=datestr(datetime);
            Message=[d(13:end),' I wish you a good approach'];
            app.MessagesTextArea.Value = sprintf(Message);
            
            %   Sets the scanner position
            app.Zscanner_Val=double(Nanonis.Get_Scanner_Z());
            Set_Zs(app)
            app.Zpy=Nanonis.Get_Encoder_Z();
            app.Zs_Val_Box.Limits=[0,app.Zs_Limit];
            
            % Initialize plot paramters
            app.Step_Average = str2double(app.DataPerdZ.Value);
            app.Zs_ext_Slider.Limits=[0, app.Zs_Extension];
            app.Zs_Step = app.Zs_Step_Box.Value;
            
            % initialize Axses and plots
            app.Frame_Val=str2double(app.Frame_Val_Box.Value);
            
            app.AC_Channel=str2double(app.AC_Channel_Box.Value);
            app.DC_Channel=str2double(app.DC_Channel_Box.Value);
            
            % Plot animated and lines
            app.AC_Animated=animatedline(app.AC_Axes,'MaximumNumPoints',app.Frame_Val);
            app.DC_Animated=animatedline(app.DC_Axes,'MaximumNumPoints',app.Frame_Val);
            
            Update_LimitsButtonPushed(app)
            
            xticks(app.AC_Axes,"auto");
            xticks(app.DC_Axes,"auto");
            
            % Define limits:
            
            
            % Plot Z_Motor
            app.Zp_Animated=animatedline(app.Z_Motor_axis,'Marker','o');
            app.Zp_Animated2=animatedline(app.Z_Motor_axis2,'Marker','o');
            
            %   Thermal ratio
            
            
            switch app.Approach_Kind.SelectedObject.Text
                case 'Thermal'
                    app.Approach_KindSelectionChanged(app)
                case 'TF'
                    app.Approach_KindSelectionChanged(app)
                case 'Other'
                    
            end
            
            
            Plot_ZpExe(app)
            
            
            % Set Timer
            app.Timer = timer(...
                'ExecutionMode', 'fixedSpacing', ...    % Run timer repeatedly
                'Period', 3e-3, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.Timer_Fun);    % Specify callback function
            start(app.Timer);
            %
        end

        % Button pushed function: Retract_Button
        function Retract_Button_Callback(app, event)
            
            app.Zscanner_Val=0;
            Set_Zs(app);
            %             Start_Stop_ButtonValueChanged(app, event);
            if app.Start_Stop_Button.Value==1
                app.Start_Stop_Button.Value=0;
            end
            Start_Stop_ButtonValueChanged(app,event)
            
            switch app.Approach_Kind.SelectedObject.Text
                case 'Thermal'
                    app.Thermal_Initial.Value= double(Nanonis.Get_Average(app.AC_Channel,20));  % up date the thermal initial index
                    Thermal_InitialValueChanged(app);
                case 'TF'
                    
                case 'Other'
                    
            end


            % Message
            d=datestr(datetime);
            Message=[d(13:end),' Retract!'];
            app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            
            
        end

        % Value changed function: Zs_ext_Slider
        function Zs_ext_Slider_Callback(app, event)
%                         app.Zscanner_Val = app.Zs_ext_Slider.Value;
%                         Set_Zs(app)
        end

        % Value changed function: Start_Stop_Button
        function Start_Stop_ButtonValueChanged(app, event)


            if  app.Start_Stop_Button.Value==1

                Update_Zs=Nanonis.Get_Scanner_Z(); % Take Zs val
                if app.Zscanner_Val~=Update_Zs
                    % If the values not agree 
                    d=datestr(datetime);
                    Message=[d(13:end),' Zs value is not update'];
                    app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
                    app.Start_Stop_Button.Value=0;
                else
%                 GetZscannerButtonPushed(app) % Take the update Z scanner
                app.Start_Stop_Text.Text = 'ON';
                app.Start_Stop_Text.FontColor = 'g';
                
                app.Run_Condition=1;
                end
            else
                app.Run_Condition=0;
                
                app.Start_Stop_Text.Text = 'OFF';
                app.Start_Stop_Text.FontColor = 'r';
            end

        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            
            % Save Messages
            d=datestr(datetime);
            File_Name1=[d(13:14),'-',d(16:17),'-',d(19:20),'_approach messages'];
            File_Name=General.Add_Time2File(File_Name1);
            General.File_Make(File_Name,{'Messages'});
            pause(0.5)
            General.Save(File_Name,flipud(app.MessagesTextArea.Value));
            General.Save(File_Name,{app.Ratio_Vec});
            General.Save(File_Name,{app.Encoder_Vec});
            % Close app
            
            try
                stop(app.Timer);
                delete(app.Timer);
            end
            delete(app)
            
        end

        % Value changed function: Frame_Val_Box
        function Frame_Val_BoxValueChanged(app, event)
            app.Frame_Val=str2double(app.Frame_Val_Box.Value);
            
            
            app.DC_Animated.MaximumNumPoints=app.Frame_Val;
            app.AC_Animated.MaximumNumPoints=app.Frame_Val;
            
            
            if  app.ACx>app.Frame_Val
                app.ClearplotButtonPushed(app)
            end
            % Update lines
            app.ACp.XData=[0,app.Frame_Val];
            app.ACn.XData=[0,app.Frame_Val];
            app.DCp.XData=[0,app.Frame_Val];
            app.DCn.XData=[0,app.Frame_Val];
            
            % Update X axes limits
            app.AC_Axes.XLim=[0 app.Frame_Val]+[-1 1].*app.ScaleY.Value;
            app.DC_Axes.XLim=[0 app.Frame_Val]+[-1 1].*app.ScaleY.Value;
            %frame
            
        end

        % Value changed function: DC_Channel_Box
        function DC_Channel_BoxValueChanged(app, event)
            app.DC_Channel=str2double(app.DC_Channel_Box.Value);
        end

        % Value changed function: DataPerdZ
        function DataPerdZValueChanged(app, event)
            app.Step_Average = str2double(app.DataPerdZ.Value);
        end

        % Value changed function: ACp_Box
        function ACp_BoxValueChanged(app, event)
            app.ACp_Box.Value=round(app.ACp_Box.Value,4);
            app.ACp.YData= [1 1].*app.ACp_Box.Value;
            UD_Axis_Limit(app)
            
            % Update limits only in thermal approach mode
            switch app.Approach_Kind.SelectedObject.Text
                case 'Thermal'
                    app.ACn_Box.Limits= [-10 , app.ACp_Box.Value-1e-5];
                case 'TF'
                    
                case 'Other'
                    
            end
            
        end

        % Value changed function: ACn_Box
        function ACn_BoxValueChanged(app, event)
            app.ACn_Box.Value=round(app.ACn_Box.Value,4);
            app.ACn.YData= [1 1].*app.ACn_Box.Value;
            UD_Axis_Limit(app)
            
            % Update limits only in thermal approach mode
            switch app.Approach_Kind.SelectedObject.Text
                case 'Thermal'
                    app.ACp_Box.Limits= [app.ACn_Box.Value+1e-5 , 10];
                case 'TF'
                    
                case 'Other'
                    
            end
            
        end

        % Value changed function: DCp_Box
        function DCp_BoxValueChanged(app, event)
            app.DCp_Box.Value=round(app.DCp_Box.Value,4);
            app.DCp.YData= [1 1].*app.DCp_Box.Value;
            UD_Axis_Limit(app)
            app.DCn_Box.Limits= [-10 , app.DCp_Box.Value-1e-5];
        end

        % Value changed function: DCn_Box
        function DCn_BoxValueChanged(app, event)
            app.DCn_Box.Value=round(app.DCn_Box.Value,4);
            app.DCn.YData= [1 1].*app.DCn_Box.Value;
            UD_Axis_Limit(app)
            app.DCp_Box.Limits= [app.DCn_Box.Value+1e-5 , 10];
        end

        % Value changed function: Zs_Val_Box
        function Zs_Val_BoxValueChanged(app, event)

            app.Zs_Val_Box.Value=round(app.Zs_Val_Box.Value,3);
            if app.Zs_Val_Box.Value<=app.Zs_Limit
                app.Zscanner_Val=app.Zs_Val_Box.Value;
                Set_Zs(app)
            else
                d=datestr(datetime);
                Message=[d(13:end),'You croosed Z scanner limit!'];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            end
        end

        % Value changed function: Zs_Ext_Box
        function Zs_Ext_BoxValueChanged(app, event)
            app.Zs_Ext_Box.Value=round(app.Zs_Ext_Box.Value,3);
            
            app.Zs_Limit=app.Zs_Ext_Box.Value;
            
            if app.Zscanner_Val>app.Zs_Limit
                app.Zscanner_Val=app.Zs_Limit;
                Set_Zs(app);
            end
            
            app.Zs_Val_Box.Limits=[0,app.Zs_Limit];
            
        end

        % Value changed function: Zs_Step_Box
        function Zs_Step_BoxValueChanged(app, event)
            app.Zs_Step_Box.Value=round(app.Zs_Step_Box.Value,3);
            app.Zs_Step = app.Zs_Step_Box.Value;
        end

        % Value changed function: Ratio_Box
        function Ratio_BoxValueChanged(app, event)


            Val=app.Thermal_Initial.Value*app.Ratio_Box.Value;
            
            if abs(Val)>10
                app.Ratio_Box.Value=10/app.Thermal_Initial.Value;
            end
            
            
            % Adhok Limits?
            
            ACp_New_Val=app.Thermal_Initial.Value*app.Ratio_Box.Value;
            if app.ACp_Box.Limits(1)>ACp_New_Val
                app.ACp_Box.Limits(1)=ACp_New_Val-app.ScaleY.Value;
            elseif app.ACp_Box.Limits(2)<ACp_New_Val
                app.ACp_Box.Limits(2)=ACp_New_Val+app.ScaleY.Value;
            end
            app.ACp_Box.Value=ACp_New_Val;
            app.ACp_BoxValueChanged(app)
        end

        % Value changed function: Thermal_Initial
        function Thermal_InitialValueChanged(app, event)
            app.Thermal_Initial.Value=round(app.Thermal_Initial.Value,4);
            Ratio_BoxValueChanged(app)
            
        end

        % Value changed function: AC_Channel_Box
        function AC_Channel_BoxValueChanged(app, event)
            app.AC_Channel=str2double(app.AC_Channel_Box.Value);
            
        end

        % Button pushed function: GetZscanner
        function GetZscannerButtonPushed(app, event)
            app.Zscanner_Val=double(Nanonis.Get_Scanner_Z());
            
            if app.Zscanner_Val<=app.Zs_Limit
                Set_Zs(app);
                 % Message
                d=datestr(datetime);
                Message=[d(13:end),' Zs is: ',num2str(app.Zscanner_Val)];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            else
                % Message
                d=datestr(datetime);
                Message=[d(13:end),' Zs crosed limit, Zs is: ',num2str(app.Zscanner_Val)];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            end
        end

        % Button pushed function: BlinkButton
        function BlinkButtonPushed(app, event)
              try
                    DAC.Blink(app.Blink_Chan.Value)
                catch
                    warning('no DAC')
              end
        end

        % Button pushed function: Update_Limits
        function Update_LimitsButtonPushed(app, event)
            
            % Take updates values
            app.ACy=Nanonis.Get_Average(app.AC_Channel,10);
            app.DCy=Nanonis.Get_Average(app.DC_Channel,10);
            
            % Rests limits
            app.ACp_Box.Limits= [-10 , 10];
            app.ACn_Box.Limits= [-10 , 10];
            app.DCp_Box.Limits= [-10 , 10];
            app.DCn_Box.Limits= [-10 , 10];
            
            
            % Update limits of lines (boxes)
            switch app.Approach_Kind.SelectedObject.Text
                case 'Thermal'
                    app.ACp_Box.Value=double(app.ACy*app.Ratio_Box.Value);
                    app.ACn_Box.Value=double(app.ACy-0.1);
                case 'TF'
                    % Think
                    app.ACp_Box.Value=double(app.ACy+0.001);   % + 1 mHz
                    app.ACn_Box.Value=double(app.ACy-0.001);   % - 1 mHz
                case 'Other'
                    app.ACp_Box.Value=double(app.ACy+0.001);
                    app.ACn_Box.Value=double(app.ACy-0.001);
            end
            
            app.DCp_Box.Value=double(app.DCy+app.ScaleY.Value);
            app.DCn_Box.Value=double(app.DCy-app.ScaleY.Value);
            
            app.ACp_BoxValueChanged(app);
            app.ACn_BoxValueChanged(app);
            app.DCp_BoxValueChanged(app);
            app.DCn_BoxValueChanged(app);
            
            % Update frame
            app.AC_Axes.YLim=[app.ACn_Box.Value app.ACp_Box.Value]+[-1 1].*1e-3;
            app.DC_Axes.YLim=[app.DCn_Box.Value app.DCp_Box.Value]+[-1 1].*1e-3;
            
            % Lines
            
            % Delete?
            app.ACp=line(app.AC_Axes,'XData', [0,app.Frame_Val], 'YData', [1 1].*app.ACp_Box.Value,'LineStyle','--','LineWidth', 2, 'Color','r');
            app.ACn=line(app.AC_Axes,'XData', [0,app.Frame_Val], 'YData', [1 1].*app.ACn_Box.Value,'LineStyle','--','LineWidth', 2, 'Color','r');
            app.DCp=line(app.DC_Axes,'XData', [0,app.Frame_Val], 'YData', [1 1].*app.DCp_Box.Value,'LineStyle','--','LineWidth', 2, 'Color','r');
            app.DCn=line(app.DC_Axes,'XData', [0,app.Frame_Val], 'YData', [1 1].*app.DCn_Box.Value,'LineStyle','--','LineWidth', 2, 'Color','r');
            
            Frame_Val_BoxValueChanged(app)
        end

        % Selection changed function: Approach_Kind
        function Approach_KindSelectionChanged(app, event)
            app.ClearplotButtonPushed(app)  % Clear the plot
            
            switch app.Approach_Kind.SelectedObject.Text
                
                
                case 'Thermal'
                    disp(0)
                    
                    app.Ratio_Vec=[];
                    app.Encoder_Vec=[];
                    app.Thermal_Ratio=1.1;
                    app.Thermal_Initial.Value=double(Nanonis.Get_Average(app.AC_Channel,20));
                    Thermal_InitialValueChanged(app);
                    title(app.AC_Axes, 'Thermal mode')
                    ylabel(app.AC_Axes, 'AC (V)')
                    
                case 'TF'
                    app.Zs_Step_Box.Value=0.001;
                    Zs_Step_BoxValueChanged(app)
                    
                    % Start the TF
                    Nanonis.OutputOn();
                    Nanonis.PllOn();
                    pause(3);
                    
                    for i=1:length(app.TF_Freqshift_Vec)
                        app.TF_Freqshift_Vec(i)=Nanonis.Getfshift();
                        pause(0.1);
                    end
                    app.TF_Mu=mean(app.TF_Freqshift_Vec);
                    app.TF_Sigma=std(app.TF_Freqshift_Vec);
                    
                    
                    % Set plots and limits of AC channel
                    app.ACp_Box.Limits= [-10 , 10];
                    app.ACn_Box.Limits= [-10 , 10];
                    
                    title(app.AC_Axes, 'TF mode')
                    ylabel(app.AC_Axes, 'Freq shift (Hz)')
                case 'Other'
            end
            
        end

        % Button pushed function: ClearplotButton
        function ClearplotButtonPushed(app, event)
            % Clear the points
            clearpoints(app.AC_Animated);
            clearpoints(app.DC_Animated);
            % Reset the x value
            app.ACx=0;
            
        end

        % Button pushed function: GetZencoderButton
        function GetZencoderButtonPushed(app, event)
            % This function read the z encoder value and print it in the
            % message window
            Z_enc=Nanonis.Get_Encoder_Z();   % Value of Z scanner
            % Message
            d=datestr(datetime);
            Message=[d(13:end),' Zencoder = ',num2str(Z_enc),' [um]'];
            app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
        end

        % Value changed function: StopTimerButton
        function StopTimerButtonValueChanged(app, event)

            if app.StopTimerButton.Value
                stop(app.Timer);
            else
                start(app.Timer);
            end
        end

        % Button pushed function: Plus_Zscanner
        function Plus_ZscannerButtonPushed(app, event)
            
            Update_Zs=Nanonis.Get_Scanner_Z(); % Take Zs val
            if app.Zscanner_Val~=Update_Zs
                % If the values not agree 
                d=datestr(datetime);
                Message=[d(13:end),'Zs value is not update'];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            else
                New_Val=app.Zscanner_Val+app.Plus_Minus_Zscanner_Step.Value;
                if New_Val<=app.Zs_Limit
                    app.Zscanner_Val=New_Val;
                    Set_Zs(app)
                else
                    d=datestr(datetime);
                    Message=[d(13:end),'You croosed Z scanner limit!'];
                    app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
                end
            end
        end

        % Button pushed function: Minus_Zscanner
        function Minus_ZscannerPushed(app, event)
            Update_Zs=Nanonis.Get_Scanner_Z(); % Take Zs val
            if app.Zscanner_Val~=Update_Zs
            % If the values not agree 
                d=datestr(datetime);
                Message=[d(13:end),'Zs value is not update'];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value]; 
            else
                New_Val=app.Zscanner_Val-app.Plus_Minus_Zscanner_Step.Value;
                if New_Val<=app.Zs_Limit
                    app.Zscanner_Val=New_Val;
                    Set_Zs(app)
                else
                    d=datestr(datetime);
                    Message=[d(13:end),'You croosed Z scanner limit!'];
                    app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'approuch';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.TabGroup = uitabgroup(app.figure1);
            app.ApproachTab = uitab(app.TabGroup);
            app.ApproachTab.Title = 'Approach';
            app.GridLayout = uigridlayout(app.ApproachTab);
            app.AC_Channel_Text = uilabel(app.GridLayout);
            app.AC_Channel_Text.Text = 'Top channel:';
            app.AC_Channel_Box = uieditfield(app.GridLayout, 'text');
            app.AC_Channel_Box.ValueChangedFcn = createCallbackFcn(app, @AC_Channel_BoxValueChanged, true);
            app.AC_Channel_Box.Value = '2';
            app.Frame_Fal_Text = uilabel(app.GridLayout);
            app.Frame_Fal_Text.Text = 'Time frame:';
            app.DC_Channel_Text = uilabel(app.GridLayout);
            app.DC_Channel_Text.Text = 'Bottom chan:';
            app.DC_Channel_Box = uieditfield(app.GridLayout, 'text');
            app.DC_Channel_Box.ValueChangedFcn = createCallbackFcn(app, @DC_Channel_BoxValueChanged, true);
            app.DC_Channel_Box.Value = '1';
            app.Frame_Val_Box = uieditfield(app.GridLayout, 'text');
            app.Frame_Val_Box.ValueChangedFcn = createCallbackFcn(app, @Frame_Val_BoxValueChanged, true);
            app.Frame_Val_Box.Value = '2000';
            app.Retract_Button = uibutton(app.GridLayout, 'push');
            app.Retract_Button.ButtonPushedFcn = createCallbackFcn(app, @Retract_Button_Callback, true);
            app.Retract_Button.Text = 'Retract';
            app.AppruchStatus_Text1 = uilabel(app.GridLayout);
            app.AppruchStatus_Text1.Text = 'Approach Status:';
            app.Zs_ext_Slider = uislider(app.GridLayout);
            app.Zs_ext_Slider.Limits = [0 22];
            app.Zs_ext_Slider.MajorTicks = [];
            app.Zs_ext_Slider.ValueChangedFcn = createCallbackFcn(app, @Zs_ext_Slider_Callback, true);
            app.Zs_ext_Slider.MinorTicks = [0 22];
            app.Zenc_Steper_Text = uilabel(app.GridLayout);
            app.Zenc_Steper_Text.Text = 'Z stepper extention [steps]';
            app.Zenc_Steper_Box = uieditfield(app.GridLayout, 'text');
            app.Zenc_Steper_Box.Value = '400';
            app.Zs_PauseStep_Text = uilabel(app.GridLayout);
            app.Zs_PauseStep_Text.Text = 'Pause after extention [S]';
            app.Zenc_PauseStep_Box = uieditfield(app.GridLayout, 'text');
            app.Zenc_PauseStep_Box.Value = '5';
            app.Start_Stop_Text = uilabel(app.GridLayout);
            app.Start_Stop_Text.Text = 'Off';
            app.text32 = uilabel(app.GridLayout);
            app.text32.Text = 'Average data between steps';
            app.DataPerdZ = uieditfield(app.GridLayout, 'text');
            app.DataPerdZ.ValueChangedFcn = createCallbackFcn(app, @DataPerdZValueChanged, true);
            app.DataPerdZ.Value = '1';
            app.Start_Stop_Button = uibutton(app.GridLayout, 'state');
            app.Start_Stop_Button.ValueChangedFcn = createCallbackFcn(app, @Start_Stop_ButtonValueChanged, true);
            app.Start_Stop_Button.Text = 'Z scanner on';
            app.ClearplotButton = uibutton(app.GridLayout, 'push');
            app.ClearplotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearplotButtonPushed, true);
            app.ClearplotButton.Text = 'Clear plot';
            app.Blink = uicheckbox(app.GridLayout);
            app.Blink.Text = 'Blink: ';
            app.Blink.Value = true;
            app.Blink_Chan = uieditfield(app.GridLayout, 'numeric');
            app.Blink_Chan.Value = 2;
            app.Retract_Condition = uicheckbox(app.GridLayout);
            app.Retract_Condition.Text = 'Retract:';
            app.Retract_Condition.Value = true;
            app.ZencodedmaxpositionumLabel = uilabel(app.GridLayout);
            app.ZencodedmaxpositionumLabel.Text = 'Z encoded max position [um]';
            app.Zencoded_Max_Box = uieditfield(app.GridLayout, 'text');
            app.Zencoded_Max_Box.Value = '4500';
            app.MessagesTextAreaLabel = uilabel(app.GridLayout);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.GridLayout);
            app.ACupperlimitVLabel = uilabel(app.GridLayout);
            app.ACupperlimitVLabel.Text = 'AC upper limit (V)';
            app.ACp_Box = uieditfield(app.GridLayout, 'numeric');
            app.ACp_Box.ValueChangedFcn = createCallbackFcn(app, @ACp_BoxValueChanged, true);
            app.AClowerlimitVEditFieldLabel = uilabel(app.GridLayout);
            app.AClowerlimitVEditFieldLabel.Text = 'AC lower limit (V)';
            app.ACn_Box = uieditfield(app.GridLayout, 'numeric');
            app.ACn_Box.ValueChangedFcn = createCallbackFcn(app, @ACn_BoxValueChanged, true);
            app.DCupperlimitVEditFieldLabel = uilabel(app.GridLayout);
            app.DCupperlimitVEditFieldLabel.Text = 'DC upper limit (V)';
            app.DCp_Box = uieditfield(app.GridLayout, 'numeric');
            app.DCp_Box.ValueChangedFcn = createCallbackFcn(app, @DCp_BoxValueChanged, true);
            app.DCp_Box.Value = 0.01;
            app.DClowerlimitVEditFieldLabel = uilabel(app.GridLayout);
            app.DClowerlimitVEditFieldLabel.Text = 'DC lower limit (V)';
            app.DCn_Box = uieditfield(app.GridLayout, 'numeric');
            app.DCn_Box.ValueChangedFcn = createCallbackFcn(app, @DCn_BoxValueChanged, true);
            app.DCn_Box.Value = -0.01;
            app.ZscannervalumLabel = uilabel(app.GridLayout);
            app.ZscannervalumLabel.Text = 'Z scanner val (um)';
            app.Zs_Val_Box = uieditfield(app.GridLayout, 'numeric');
            app.Zs_Val_Box.Limits = [0 22];
            app.Zs_Val_Box.ValueDisplayFormat = '%.3f';
            app.Zs_Val_Box.ValueChangedFcn = createCallbackFcn(app, @Zs_Val_BoxValueChanged, true);
            app.ZscannerextentionlimitumEditFieldLabel = uilabel(app.GridLayout);
            app.ZscannerextentionlimitumEditFieldLabel.Text = 'Z scanner extention limit  (um)';
            app.Zs_Ext_Box = uieditfield(app.GridLayout, 'numeric');
            app.Zs_Ext_Box.Limits = [0 22];
            app.Zs_Ext_Box.ValueChangedFcn = createCallbackFcn(app, @Zs_Ext_BoxValueChanged, true);
            app.Zs_Ext_Box.Value = 22;
            app.ZsstepumLabel = uilabel(app.GridLayout);
            app.ZsstepumLabel.Text = 'Zs step (um)';
            app.Zs_Step_Box = uieditfield(app.GridLayout, 'numeric');
            app.Zs_Step_Box.Limits = [0 22];
            app.Zs_Step_Box.ValueChangedFcn = createCallbackFcn(app, @Zs_Step_BoxValueChanged, true);
            app.ThermalRatioLabel = uilabel(app.GridLayout);
            app.ThermalRatioLabel.Text = 'Thermal Ratio:';
            app.Ratio_Box = uieditfield(app.GridLayout, 'numeric');
            app.Ratio_Box.Limits = [0 22];
            app.Ratio_Box.ValueChangedFcn = createCallbackFcn(app, @Ratio_BoxValueChanged, true);
            app.Ratio_Box.Value = 2;
            app.ThermalInitialLabel = uilabel(app.GridLayout);
            app.ThermalInitialLabel.Text = 'Thermal Initial:';
            app.Thermal_Initial = uieditfield(app.GridLayout, 'numeric');
            app.Thermal_Initial.ValueChangedFcn = createCallbackFcn(app, @Thermal_InitialValueChanged, true);
            app.YoffsetLabel = uilabel(app.GridLayout);
            app.YoffsetLabel.Text = 'Y offset';
            app.ScaleY = uieditfield(app.GridLayout, 'numeric');
            app.ScaleY.Value = 0.0005;
            app.Retract_Condition_Val = uieditfield(app.GridLayout, 'numeric');
            app.Retract_Condition_Val.Value = 0.2;
            app.umLabel = uilabel(app.GridLayout);
            app.umLabel.Text = '[um]';
            app.GetZscanner = uibutton(app.GridLayout, 'push');
            app.GetZscanner.ButtonPushedFcn = createCallbackFcn(app, @GetZscannerButtonPushed, true);
            app.GetZscanner.Text = 'Get Zscanner';
            app.Update_Limits = uibutton(app.GridLayout, 'push');
            app.Update_Limits.ButtonPushedFcn = createCallbackFcn(app, @Update_LimitsButtonPushed, true);
            app.Update_Limits.Text = 'Update limits';
            app.Approach_Kind = uibuttongroup(app.GridLayout);
            app.Approach_Kind.SelectionChangedFcn = createCallbackFcn(app, @Approach_KindSelectionChanged, true);
            app.Approach_Kind.Title = 'Approach';
            app.Thermal = uiradiobutton(app.Approach_Kind);
            app.Thermal.Text = 'Thermal';
            app.Thermal.Value = true;
            app.TF = uiradiobutton(app.Approach_Kind);
            app.TF.Text = 'TF';
            app.Other = uiradiobutton(app.Approach_Kind);
            app.Other.Text = 'Other';
            app.ChanLabel = uilabel(app.GridLayout);
            app.ChanLabel.Text = '[Chan]';
            app.BlinkButton = uibutton(app.GridLayout, 'push');
            app.BlinkButton.ButtonPushedFcn = createCallbackFcn(app, @BlinkButtonPushed, true);
            app.BlinkButton.Text = 'Blink';
            app.TFAvrEditFieldLabel = uilabel(app.GridLayout);
            app.TFAvrEditFieldLabel.Text = 'TF Avr';
            app.TFAvr = uieditfield(app.GridLayout, 'numeric');
            app.TFAvr.Limits = [5 100];
            app.TFAvr.Value = 100;
            app.TFSigmaLabel = uilabel(app.GridLayout);
            app.TFSigmaLabel.Text = 'TF Sigma';
            app.TF_Sigma_Factor = uieditfield(app.GridLayout, 'numeric');
            app.TF_Sigma_Factor.Limits = [2 100];
            app.TF_Sigma_Factor.Value = 7;
            app.GetZencoderButton = uibutton(app.GridLayout, 'push');
            app.GetZencoderButton.ButtonPushedFcn = createCallbackFcn(app, @GetZencoderButtonPushed, true);
            app.GetZencoderButton.Text = 'Get Z encoder';
            app.StopTimerButton = uibutton(app.GridLayout, 'state');
            app.StopTimerButton.ValueChangedFcn = createCallbackFcn(app, @StopTimerButtonValueChanged, true);
            app.StopTimerButton.Text = 'Stop Timer';
            app.Plus_Zscanner = uibutton(app.GridLayout, 'push');
            app.Plus_Zscanner.ButtonPushedFcn = createCallbackFcn(app, @Plus_ZscannerButtonPushed, true);
            app.Plus_Zscanner.Text = '+';
            app.Minus_Zscanner = uibutton(app.GridLayout, 'push');
            app.Minus_Zscanner.ButtonPushedFcn = createCallbackFcn(app, @Minus_ZscannerPushed, true);
            app.Minus_Zscanner.Text = '-';
            app.stepsizeumLabel = uilabel(app.GridLayout);
            app.stepsizeumLabel.Text = '   +/- step size [um] :';
            app.Plus_Minus_Zscanner_Step = uieditfield(app.GridLayout, 'numeric');
            app.Plus_Minus_Zscanner_Step.Value = 0.1;
            app.Z_Motor_axis2 = uiaxes(app.GridLayout);
            title(app.Z_Motor_axis2, '')
            xlabel(app.Z_Motor_axis2, 'Encoder value [um]')
            ylabel(app.Z_Motor_axis2, 'Thermal Ratio')
            app.DC_Axes = uiaxes(app.GridLayout);
            title(app.DC_Axes, 'DC')
            xlabel(app.DC_Axes, 'Time (S)')
            ylabel(app.DC_Axes, 'DC V')
            app.DC_Axes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.AC_Axes = uiaxes(app.GridLayout);
            title(app.AC_Axes, 'AC')
            xlabel(app.AC_Axes, 'Time (s)')
            ylabel(app.AC_Axes, 'AC (V)')
            app.AC_Axes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Z_Motor_axis = uiaxes(app.GridLayout);
            title(app.Z_Motor_axis, 'Zmotor encoder')
            xlabel(app.Z_Motor_axis, 'Itteration')
            ylabel(app.Z_Motor_axis, 'Encoder value [um]')
            app.ContextMenu = uicontextmenu(app.figure1);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Approach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Approuch_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end