classdef app2_converted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        Alt_ApproachTab              matlab.ui.container.Tab
        ApproachSettingsPanel        matlab.ui.container.Panel
        ApproachModeCheckBox         matlab.ui.control.CheckBox
        nomotorstepsEditField        matlab.ui.control.NumericEditField
        nomotorstepsEditFieldLabel   matlab.ui.control.Label
        ZEncoderumEditField          matlab.ui.control.NumericEditField
        ZEncoderumEditFieldLabel     matlab.ui.control.Label
        BlinkCheckBox                matlab.ui.control.CheckBox
        TextArea                     matlab.ui.control.TextArea
        ControlPanel                 matlab.ui.container.Panel
        ZScannerumEditField          matlab.ui.control.NumericEditField
        ZScannerumEditFieldLabel     matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        Lamp_2                       matlab.ui.control.Lamp
        PokeButton                   matlab.ui.control.Button
        BlinkButton                  matlab.ui.control.Button
        PokeSettingsPanel            matlab.ui.container.Panel
        TouchPointumEditField        matlab.ui.control.NumericEditField
        TouchPointumEditFieldLabel   matlab.ui.control.Label
        ThresholdmHzEditField        matlab.ui.control.NumericEditField
        ThresholdmHzEditFieldLabel   matlab.ui.control.Label
        AvgSpinner                   matlab.ui.control.Spinner
        AvgSpinnerLabel              matlab.ui.control.Label
        UpdateThresholdButton        matlab.ui.control.Button
        RetractnmSpinner             matlab.ui.control.Spinner
        RetractnmSpinnerLabel        matlab.ui.control.Label
        sig_noSpinner                matlab.ui.control.Spinner
        sig_noSpinnerLabel           matlab.ui.control.Label
        TipSpeednmsSpinner           matlab.ui.control.Spinner
        TipSpeednmsSpinnerLabel      matlab.ui.control.Label
        NoisePanel                   matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        NoisemaxmHzEditField         matlab.ui.control.NumericEditField
        NoisemaxmHzEditFieldLabel    matlab.ui.control.Label
        STDmHzEditField              matlab.ui.control.NumericEditField
        STDmHzEditFieldLabel         matlab.ui.control.Label
        GetNoiseButton               matlab.ui.control.Button
        ThermalTab                   matlab.ui.container.Tab
        GridLayout_2                 matlab.ui.container.GridLayout
        Plus_Minus_Zscanner_Step     matlab.ui.control.NumericEditField
        stepsizeumLabel              matlab.ui.control.Label
        Minus_Zscanner               matlab.ui.control.Button
        Plus_Zscanner                matlab.ui.control.Button
        StopTimerButton              matlab.ui.control.StateButton
        GetZencoderButton            matlab.ui.control.Button
        TF_Sigma_Factor              matlab.ui.control.NumericEditField
        TFSigmaLabel                 matlab.ui.control.Label
        TFAvr                        matlab.ui.control.NumericEditField
        TFAvrEditFieldLabel          matlab.ui.control.Label
        BlinkButton_2                matlab.ui.control.Button
        ChanLabel                    matlab.ui.control.Label
        Update_Limits                matlab.ui.control.Button
        GetZscanner                  matlab.ui.control.Button
        umLabel                      matlab.ui.control.Label
        Retract_Condition_Val        matlab.ui.control.NumericEditField
        ScaleY                       matlab.ui.control.NumericEditField
        YoffsetLabel                 matlab.ui.control.Label
        Thermal_Initial              matlab.ui.control.NumericEditField
        ThermalInitialLabel          matlab.ui.control.Label
        Ratio_Box                    matlab.ui.control.NumericEditField
        ThermalRatioLabel            matlab.ui.control.Label
        Zs_Step_Box                  matlab.ui.control.NumericEditField
        ZsstepumLabel                matlab.ui.control.Label
        Zs_Ext_Box                   matlab.ui.control.NumericEditField
        ZscannerextentionlimitumEditFieldLabel  matlab.ui.control.Label
        Zs_Val_Box                   matlab.ui.control.NumericEditField
        ZscannervalumLabel           matlab.ui.control.Label
        DCn_Box                      matlab.ui.control.NumericEditField
        DClowerlimitVEditFieldLabel  matlab.ui.control.Label
        DCp_Box                      matlab.ui.control.NumericEditField
        DCupperlimitVEditFieldLabel  matlab.ui.control.Label
        ACn_Box                      matlab.ui.control.NumericEditField
        AClowerlimitVEditFieldLabel  matlab.ui.control.Label
        ACp_Box                      matlab.ui.control.NumericEditField
        ACupperlimitVLabel           matlab.ui.control.Label
        MessagesTextArea             matlab.ui.control.TextArea
        MessagesTextAreaLabel        matlab.ui.control.Label
        Zencoded_Max_Box             matlab.ui.control.EditField
        ZencodedmaxpositionumLabel   matlab.ui.control.Label
        Retract_Condition            matlab.ui.control.CheckBox
        Blink_Chan                   matlab.ui.control.NumericEditField
        Blink                        matlab.ui.control.CheckBox
        ClearplotButton              matlab.ui.control.Button
        Start_Stop_Button            matlab.ui.control.StateButton
        DataPerdZ                    matlab.ui.control.EditField
        text32                       matlab.ui.control.Label
        Start_Stop_Text              matlab.ui.control.Label
        Zenc_PauseStep_Box           matlab.ui.control.EditField
        Zs_PauseStep_Text            matlab.ui.control.Label
        Zenc_Steper_Box              matlab.ui.control.EditField
        Zenc_Steper_Text             matlab.ui.control.Label
        Zs_ext_Slider                matlab.ui.control.Slider
        AppruchStatus_Text1          matlab.ui.control.Label
        Retract_Button               matlab.ui.control.Button
        Frame_Val_Box                matlab.ui.control.EditField
        DC_Channel_Box               matlab.ui.control.EditField
        DC_Channel_Text              matlab.ui.control.Label
        Frame_Fal_Text               matlab.ui.control.Label
        AC_Channel_Box               matlab.ui.control.EditField
        AC_Channel_Text              matlab.ui.control.Label
        Z_Motor_axis2                matlab.ui.control.UIAxes
        Z_Motor_axis                 matlab.ui.control.UIAxes
        DC_Axes                      matlab.ui.control.UIAxes
        AC_Axes                      matlab.ui.control.UIAxes
        Tab3                         matlab.ui.container.Tab
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    %% Alt_approach properties and call back methods
    properties (Access = public)
        Ctrl_index = 10;
        %fshift_ch_index = 34; % Fshift Index in the Data Logger module
        fshift_ch = 18; % Channel Index in the Data Logger module (Test!)
        mu_ch = 3; % Channel Index that holds the average (Output 3)
        blink_ch = 2; % Channel Index for DAC
        
        Acq_dur = 5; BaseName = "noise";
        
        % find a better directory to log the noise! so it will be automatic
        
       Session_Folder = [Nanonis.Get_Session_Folder(),'\']; 

        sigma; mu; noise_max; Threshold; Z_Pos; Stop = 0; fshift;
        retract = 200; tip_speed = 10; scanner_limit = 22; sig_no = 6; mu_avg = 200;
        PI_Const = 10^-6;
        
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TextArea.Value = 'Welcome to the new Approach app. Hope you like it!';
            % Deleting Files
            k = ceil(0.8*app.Acq_dur);
            for i=1:k
                file_name = strcat(app.Session_Folder, app.BaseName, "00",num2str(i),".dat");
                if isfile(file_name)
                    delete(file_name)
                end
            end
            % Z-Encoder
         app.ZEncoderumEditField.Value = Nanonis.Get_Encoder_Z();
        end

        % Value changed function: TipSpeednmsSpinner
        function TipSpeednmsSpinnerValueChanged(app, event)
            app.tip_speed = app.TipSpeednmsSpinner.Value;
            Nanonis.ZCtrl_SetGain(app.tip_speed*10^-9, app.PI_Const, 0)
        end

        % Value changed function: RetractnmSpinner
        function RetractnmSpinnerValueChanged(app, event)
            app.retract = app.RetractnmSpinner.Value;
            Nanonis.ZCtrl_SetHome(app.retract*10^-9)
        end

        % Button pushed function: GetNoiseButton
        function GetNoiseButtonPushed(app, event)
            Alt_Approach_functions.get_noise()
        end

        % Button pushed function: PokeButton
        function PokeButtonPushed(app, event)
            Alt_Approach_functions.poke()
            Nanonis.PllOff();
            Nanonis.OutputOff();
        end

        % Button pushed function: UpdateThresholdButton
        function UpdateThresholdButtonPushed(app, event)
            app.Threshold = app.sigma*app.sig_no; % Calculating Threshold
            app.ThresholdmHzEditField.Value = app.Threshold*1000; % Update Field
            Nanonis.SafeTip_SetThreshold(app.Threshold) % Setting Threshold
        end

        % Value changed function: sig_noSpinner
        function sig_noSpinnerValueChanged(app, event)
            app.sig_no = app.sig_noSpinner.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Stop = 1;
        end

        % Value changed function: AvgSpinner
        function AvgSpinnerValueChanged(app, event)
            app.mu_avg = app.AvgSpinner.Value;
        end

        % Button pushed function: BlinkButton
        function BlinkButtonPushed(app, event)
            DAC.Blink(app.blink_ch);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Nanonis.set_ZCtrl(app.Ctrl_index,app.PI_Const,app.tip_speed,app.retract)
            Nanonis.SafeTip_SetOnOff(2)
            
            delete(app)
            
        end

        % Value changed function: ApproachModeCheckBox
        function ApproachModeCheckBoxValueChanged(app, event)
            value = app.ApproachModeCheckBox.Value;
            if value
                app.PokeButton.Text = 'Approach';
            else
                app.PokeButton.Text = 'Poke';
            end
        end
    end

  %% 

  %% Thermal approach properties and functions
    properties (Access = private)
        ACx=0 % Description
        ACy=0 % Description
        DCx=0 % Description
        DCy=0 % Description
        Zpx=1; % Description
        Zpy % Description
        Scale_X=0.1 % Description
        ACp % Description
        ACn % Description
        DCp % Description
        DCn % Description
        Frame_Val % Description
        DC_Animated % Description
        AC_Animated % Description
        Zp_Animated % Description
        Zp_Animated2 % Description
        Zscanner_Val=0 % Description
        Zs_Limit=22 % Description  % Take from Nanonis...
        Zs_Extension=22 % Description
        Run_Condition=0 % Need to be true for the software to run
        AC_Channel; % Description
        DC_Channel; % Description
        Zs_Step % Description
        Timer % Description
        Step_Average % Description
        Thermal_c % Description
        Thermal_Ratio % Description
        Encoder_Vec % Description
        Ratio_Vec % Description
        TF_Freqshift_Vec=zeros(1,103) % Description
        TF_Mu % Description
        TF_Sigma % Description

        %Ehud tests - change/delete
        test_x = []
        test_y = []
        test_x2 = []
        test_y2 = []
        tictoc = tic
    end


    % Callbacks that handle component events
    methods (Access = private)
        
        % Code that executes after component creation
        function Approuch_OpeningFcn(app)
            % When opening the GUI set the variable to the default values
            
            %   Plot message
            d=datestr(datetime);
            Message=[d(13:end),' I wish you a good approach'];
            app.MessagesTextArea.Value = sprintf(Message);
            
            %   Sets the scanner position
            app.Zscanner_Val=double(Nanonis.Get_Scanner_Z());
            Set_Zs(app)
            app.Zpy=Nanonis.Get_Encoder_Z();
            app.Zs_Val_Box.Limits=[0,app.Zs_Limit];
            
            % Initialize plot paramters
            app.Step_Average = str2double(app.DataPerdZ.Value);
            app.Zs_ext_Slider.Limits=[0, app.Zs_Extension];
            app.Zs_Step = app.Zs_Step_Box.Value;
            
            % initialize Axses and plots
            app.Frame_Val=str2double(app.Frame_Val_Box.Value);
            
            app.AC_Channel=str2double(app.AC_Channel_Box.Value);
            app.DC_Channel=str2double(app.DC_Channel_Box.Value);
            
            % Plot animated and lines
            app.AC_Animated=animatedline(app.AC_Axes,'MaximumNumPoints',app.Frame_Val);
            app.DC_Animated=animatedline(app.DC_Axes,'MaximumNumPoints',app.Frame_Val);
            
            Update_LimitsButtonPushed(app)
            
            xticks(app.AC_Axes,"auto");
            xticks(app.DC_Axes,"auto");
            
            % Define limits:
            
            
            % Plot Z_Motor
            app.Zp_Animated=animatedline(app.Z_Motor_axis,'Marker','o');
            app.Zp_Animated2=animatedline(app.Z_Motor_axis2,'Marker','o');
            
            %   Thermal ratio
            
            
            switch app.Approach_Kind.SelectedObject.Text
                case 'Thermal'
                    app.Approach_KindSelectionChanged(app)
                case 'TF'
                    app.Approach_KindSelectionChanged(app)
                case 'Other'
                    
            end
            
            
            Plot_ZpExe(app)
            
            
            % Set Timer
            app.Timer = timer(...
                'ExecutionMode', 'fixedSpacing', ...    % Run timer repeatedly
                'Period', 3e-3, ...                     % Period is 1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.Timer_Fun);    % Specify callback function
            start(app.Timer);
            %
        end

        % Button pushed function: Retract_Button
        function Retract_Button_Callback(app, event)
            
            app.Zscanner_Val=0;
            Thermal_Approach_functions.Set_Zs(app);
            %             Start_Stop_ButtonValueChanged(app, event);
            if app.Start_Stop_Button.Value==1
                app.Start_Stop_Button.Value=0;
            end
            Start_Stop_ButtonValueChanged(app,event)
            app.Thermal_Initial.Value= double(Nanonis.Get_Average(app.AC_Channel,20));  % up date the thermal initial index
            Thermal_InitialValueChanged(app);
               
            % Message
            d=datestr(datetime);
            Message=[d(13:end),' Retract!'];
            app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            
            
        end

        % Value changed function: Zs_ext_Slider
        function Zs_ext_Slider_Callback(app, event)
            %             app.Zscanner_Val = app.Zs_ext_Slider.Value;
            %             Set_Zs(app)
        end

        % Value changed function: Start_Stop_Button
        function Start_Stop_ButtonValueChanged(app, event)


            if  app.Start_Stop_Button.Value==1
                Update_Zs=Nanonis.Get_Scanner_Z(); % Take Zs val
                if app.Zscanner_Val~=Update_Zs
                    % If the values not agree 
                    d=datestr(datetime);
                    Message=[d(13:end),'Zs value is not update'];
                    app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
                    app.Start_Stop_Button.Value=0;
                else
%                 GetZscannerButtonPushed(app) % Take the update Z scanner
                app.Start_Stop_Text.Text = 'ON';
                app.Start_Stop_Text.FontColor = 'g';
                
                app.Run_Condition=1;
                end
            else
                app.Run_Condition=0;
                
                app.Start_Stop_Text.Text = 'OFF';
                app.Start_Stop_Text.FontColor = 'r';
            end

        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            
            % Save Messages
            d=datestr(datetime);
            File_Name1=[d(13:14),'-',d(16:17),'-',d(19:20),'_approach messages'];
            File_Name=General.Add_Time2File(File_Name1);
            General.File_Make(File_Name,{'Messages'});
            pause(0.5)
            General.Save(File_Name,flipud(app.MessagesTextArea.Value));
            General.Save(File_Name,{app.Ratio_Vec});
            General.Save(File_Name,{app.Encoder_Vec});
            % Close app
            
            try
                stop(app.Timer);
                delete(app.Timer);
            end
            delete(app)
            
        end

        % Value changed function: Frame_Val_Box
        function Frame_Val_BoxValueChanged(app, event)
            app.Frame_Val=str2double(app.Frame_Val_Box.Value);
            
            
            app.DC_Animated.MaximumNumPoints=app.Frame_Val;
            app.AC_Animated.MaximumNumPoints=app.Frame_Val;
            
            
            if  app.ACx>app.Frame_Val
                app.ClearplotButtonPushed(app)
            end
            % Update lines
            app.ACp.XData=[0,app.Frame_Val];
            app.ACn.XData=[0,app.Frame_Val];
            app.DCp.XData=[0,app.Frame_Val];
            app.DCn.XData=[0,app.Frame_Val];
            
            % Update X axes limits
            app.AC_Axes.XLim=[0 app.Frame_Val]+[-1 1].*app.ScaleY.Value;
            app.DC_Axes.XLim=[0 app.Frame_Val]+[-1 1].*app.ScaleY.Value;
            %frame
            
        end

        % Value changed function: DC_Channel_Box
        function DC_Channel_BoxValueChanged(app, event)
            app.DC_Channel=str2double(app.DC_Channel_Box.Value);
        end

        % Value changed function: DataPerdZ
        function DataPerdZValueChanged(app, event)
            app.Step_Average = str2double(app.DataPerdZ.Value);
        end

        % Value changed function: ACp_Box
        function ACp_BoxValueChanged(app, event)
            app.ACp_Box.Value=round(app.ACp_Box.Value,4);
            app.ACp.YData= [1 1].*app.ACp_Box.Value;
            Thermal_Approach_functions.UD_Axis_Limit(app)
            
            % Update limits only in thermal approach mode
            
            app.ACn_Box.Limits= [-10 , app.ACp_Box.Value-1e-5];
               
            
        end

        % Value changed function: ACn_Box
        function ACn_BoxValueChanged(app, event)
            app.ACn_Box.Value=round(app.ACn_Box.Value,4);
            app.ACn.YData= [1 1].*app.ACn_Box.Value;
            Thermal_Approach_functions.UD_Axis_Limit(app)
            
            % Update limits only in thermal approach mode
            
             app.ACp_Box.Limits= [app.ACn_Box.Value+1e-5 , 10];
              
            
        end

        % Value changed function: DCp_Box
        function DCp_BoxValueChanged(app, event)
            app.DCp_Box.Value=round(app.DCp_Box.Value,4);
            app.DCp.YData= [1 1].*app.DCp_Box.Value;
            Thermal_Approach_functions.UD_Axis_Limit(app)
            app.DCn_Box.Limits= [-10 , app.DCp_Box.Value-1e-5];
        end

        % Value changed function: DCn_Box
        function DCn_BoxValueChanged(app, event)
            app.DCn_Box.Value=round(app.DCn_Box.Value,4);
            app.DCn.YData= [1 1].*app.DCn_Box.Value;
            Thermal_Approach_functions.UD_Axis_Limit(app)
            app.DCp_Box.Limits= [app.DCn_Box.Value+1e-5 , 10];
        end

        % Value changed function: Zs_Val_Box
        function Zs_Val_BoxValueChanged(app, event)

            app.Zs_Val_Box.Value=round(app.Zs_Val_Box.Value,3);
            if app.Zs_Val_Box.Value<=app.Zs_Limit
                app.Zscanner_Val=app.Zs_Val_Box.Value;
                Thermal_Approach_functions.Set_Zs(app)
            else
                d=datestr(datetime);
                Message=[d(13:end),'You croosed Z scanner limit!'];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            end
        end

        % Value changed function: Zs_Ext_Box
        function Zs_Ext_BoxValueChanged(app, event)
            app.Zs_Ext_Box.Value=round(app.Zs_Ext_Box.Value,3);
            
            app.Zs_Limit=app.Zs_Ext_Box.Value;
            
            if app.Zscanner_Val>app.Zs_Limit
                app.Zscanner_Val=app.Zs_Limit;
                Thermal_Approach_functions.Set_Zs(app);
            end
            
            app.Zs_Val_Box.Limits=[0,app.Zs_Limit];
            
        end

        % Value changed function: Zs_Step_Box
        function Zs_Step_BoxValueChanged(app, event)
            app.Zs_Step_Box.Value=round(app.Zs_Step_Box.Value,3);
            app.Zs_Step = app.Zs_Step_Box.Value;
        end

        % Value changed function: Ratio_Box
        function Ratio_BoxValueChanged(app, event)


            Val=app.Thermal_Initial.Value*app.Ratio_Box.Value;
            
            if abs(Val)>10
                app.Ratio_Box.Value=10/app.Thermal_Initial.Value;
            end
            
            
            % Adhok Limits?
            
            ACp_New_Val=app.Thermal_Initial.Value*app.Ratio_Box.Value;
            if app.ACp_Box.Limits(1)>ACp_New_Val
                app.ACp_Box.Limits(1)=ACp_New_Val-app.ScaleY.Value;
            elseif app.ACp_Box.Limits(2)<ACp_New_Val
                app.ACp_Box.Limits(2)=ACp_New_Val+app.ScaleY.Value;
            end
            app.ACp_Box.Value=ACp_New_Val;
            app.ACp_BoxValueChanged(app)
        end

        % Value changed function: Thermal_Initial
        function Thermal_InitialValueChanged(app, event)
            app.Thermal_Initial.Value=round(app.Thermal_Initial.Value,4);
            Ratio_BoxValueChanged(app)
            
        end

        % Value changed function: AC_Channel_Box
        function AC_Channel_BoxValueChanged(app, event)
            app.AC_Channel=str2double(app.AC_Channel_Box.Value);
            
        end

        % Button pushed function: GetZscanner
        function GetZscannerButtonPushed(app, event)
            app.Zscanner_Val=double(Nanonis.Get_Scanner_Z());
            
            if app.Zscanner_Val<=app.Zs_Limit
                Thermal_Approach_functions.Set_Zs(app);
            else
                % Message
                d=datestr(datetime);
                Message=[d(13:end),' Zs crosed limit, Zs is: ',num2str(app.Zscanner_Val)];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            end
        end

        % Button pushed function: BlinkButton
        function BlinkButtonPushed2(app, event)
            DAC.Blink(app.Blink_Chan.Value);
        end

        % Button pushed function: Update_Limits
        function Update_LimitsButtonPushed(app, event)
            
            % Take updates values
            app.ACy=Nanonis.Get_Average(app.AC_Channel,10);
            app.DCy=Nanonis.Get_Average(app.DC_Channel,10);
            
            % Rests limits
            app.ACp_Box.Limits= [-10 , 10];
            app.ACn_Box.Limits= [-10 , 10];
            app.DCp_Box.Limits= [-10 , 10];
            app.DCn_Box.Limits= [-10 , 10];
            
            
            % Update limits of lines (boxes)
              
             app.ACp_Box.Value=double(app.ACy*app.Ratio_Box.Value);
             app.ACn_Box.Value=double(app.ACy-0.1);
               
            
            app.DCp_Box.Value=double(app.DCy+app.ScaleY.Value);
            app.DCn_Box.Value=double(app.DCy-app.ScaleY.Value);
            
            app.ACp_BoxValueChanged(app);
            app.ACn_BoxValueChanged(app);
            app.DCp_BoxValueChanged(app);
            app.DCn_BoxValueChanged(app);
            
            % Update frame
            app.AC_Axes.YLim=[app.ACn_Box.Value app.ACp_Box.Value]+[-1 1].*1e-3;
            app.DC_Axes.YLim=[app.DCn_Box.Value app.DCp_Box.Value]+[-1 1].*1e-3;
            
            % Lines
            
            % Delete?
            app.ACp=line(app.AC_Axes,'XData', [0,app.Frame_Val], 'YData', [1 1].*app.ACp_Box.Value,'LineStyle','--','LineWidth', 2, 'Color','r');
            app.ACn=line(app.AC_Axes,'XData', [0,app.Frame_Val], 'YData', [1 1].*app.ACn_Box.Value,'LineStyle','--','LineWidth', 2, 'Color','r');
            app.DCp=line(app.DC_Axes,'XData', [0,app.Frame_Val], 'YData', [1 1].*app.DCp_Box.Value,'LineStyle','--','LineWidth', 2, 'Color','r');
            app.DCn=line(app.DC_Axes,'XData', [0,app.Frame_Val], 'YData', [1 1].*app.DCn_Box.Value,'LineStyle','--','LineWidth', 2, 'Color','r');
            
            Frame_Val_BoxValueChanged(app)
        end

        % Selection changed function: Approach_Kind
        function Approach_KindSelectionChanged(app, event)
            app.ClearplotButtonPushed(app)  % Clear the plot
            
         
                    disp(0)
                    
                    app.Ratio_Vec=[];
                    app.Encoder_Vec=[];
                    app.Thermal_Ratio=1.1;
                    app.Thermal_Initial.Value=double(Nanonis.Get_Average(app.AC_Channel,20));
                    Thermal_InitialValueChanged(app);
                    title(app.AC_Axes, 'Thermal mode')
                    ylabel(app.AC_Axes, 'AC (V)')


        end

        % Button pushed function: ClearplotButton
        function ClearplotButtonPushed(app, event)
            % Clear the points
            clearpoints(app.AC_Animated);
            clearpoints(app.DC_Animated);
            % Reset the x value
            app.ACx=0;
            
        end

        % Button pushed function: GetZencoderButton
        function GetZencoderButtonPushed(app, event)
            % This function read the z encoder value and print it in the
            % message window
            Z_enc=Nanonis.Get_Encoder_Z();   % Value of Z scanner
            % Message
            d=datestr(datetime);
            Message=[d(13:end),' Zencoder = ',num2str(Z_enc),' [um]'];
            app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
        end

        % Value changed function: StopTimerButton
        function StopTimerButtonValueChanged(app, event)
            if app.StopTimerButton.Value
                stop(app.Timer);
            else
                start(app.Timer);
            end
        end

        % Button pushed function: Plus_Zscanner
        function Plus_ZscannerButtonPushed(app, event)
            
            Update_Zs=Nanonis.Get_Scanner_Z(); % Take Zs val
            if app.Zscanner_Val~=Update_Zs
                % If the values not agree 
                d=datestr(datetime);
                Message=[d(13:end),'Zs value is not update'];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
            else
                New_Val=app.Zscanner_Val+app.Plus_Minus_Zscanner_Step.Value;
                if New_Val<=app.Zs_Limit
                    app.Zscanner_Val=New_Val;
                    Thermal_Approach_functions.Set_Zs(app)
                else
                    d=datestr(datetime);
                    Message=[d(13:end),'You croosed Z scanner limit!'];
                    app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
                end
            end
        end

        % Button pushed function: Minus_Zscanner
        function Minus_ZscannerPushed(app, event)
            Update_Zs=Nanonis.Get_Scanner_Z(); % Take Zs val
            if app.Zscanner_Val~=Update_Zs
            % If the values not agree 
                d=datestr(datetime);
                Message=[d(13:end),'Zs value is not update'];
                app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value]; 
            else
                New_Val=app.Zscanner_Val-app.Plus_Minus_Zscanner_Step.Value;
                 if New_Val<=app.Zs_Limit
                    app.Zscanner_Val=New_Val;
                    Thermal_Approach_functions.Set_Zs(app)
                else
                    d=datestr(datetime);
                    Message=[d(13:end),'You croosed Z scanner limit!'];
                    app.MessagesTextArea.Value = [Message;app.MessagesTextArea.Value];
                end
            end
        end
    end

%%


    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {804, 804};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {55, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Alt_ApproachTab = uitab(app.TabGroup);
            app.Alt_ApproachTab.Title = 'Alt_Approach';
            app.NoisePanel = uipanel(app.Alt_ApproachTab);
            app.NoisePanel.Title = 'Noise';
            app.GetNoiseButton = uibutton(app.NoisePanel, 'push');
            app.GetNoiseButton.Text = 'Get Noise';
            app.STDmHzEditFieldLabel = uilabel(app.NoisePanel);
            app.STDmHzEditFieldLabel.Text = 'STD (mHz)';
            app.STDmHzEditField = uieditfield(app.NoisePanel, 'numeric');
            app.NoisemaxmHzEditFieldLabel = uilabel(app.NoisePanel);
            app.NoisemaxmHzEditFieldLabel.Text = 'Noise max (mHz)';
            app.NoisemaxmHzEditField = uieditfield(app.NoisePanel, 'numeric');
            app.Lamp = uilamp(app.NoisePanel);
            app.PokeSettingsPanel = uipanel(app.Alt_ApproachTab);
            app.PokeSettingsPanel.Title = 'Poke Settings';
            app.TipSpeednmsSpinnerLabel = uilabel(app.PokeSettingsPanel);
            app.TipSpeednmsSpinnerLabel.Text = 'Tip Speed (nm/s)';
            app.TipSpeednmsSpinner = uispinner(app.PokeSettingsPanel);
            app.TipSpeednmsSpinner.Step = 5;
            app.TipSpeednmsSpinner.Limits = [5 200];
            app.TipSpeednmsSpinner.Value = 10;
            app.sig_noSpinnerLabel = uilabel(app.PokeSettingsPanel);
            app.sig_noSpinnerLabel.Text = 'sig_no';
            app.sig_noSpinner = uispinner(app.PokeSettingsPanel);
            app.sig_noSpinner.Value = 6;
            app.RetractnmSpinnerLabel = uilabel(app.PokeSettingsPanel);
            app.RetractnmSpinnerLabel.Text = 'Retract (nm)';
            app.RetractnmSpinner = uispinner(app.PokeSettingsPanel);
            app.RetractnmSpinner.Step = 50;
            app.RetractnmSpinner.Limits = [0 1000];
            app.RetractnmSpinner.Value = 200;
            app.UpdateThresholdButton = uibutton(app.PokeSettingsPanel, 'push');
            app.UpdateThresholdButton.Text = 'Update Threshold';
            app.AvgSpinnerLabel = uilabel(app.PokeSettingsPanel);
            app.AvgSpinnerLabel.Text = 'Avg';
            app.AvgSpinner = uispinner(app.PokeSettingsPanel);
            app.AvgSpinner.Step = 50;
            app.AvgSpinner.Limits = [50 500];
            app.AvgSpinner.Value = 200;
            app.ThresholdmHzEditFieldLabel = uilabel(app.PokeSettingsPanel);
            app.ThresholdmHzEditFieldLabel.Text = 'Threshold (mHz)';
            app.ThresholdmHzEditField = uieditfield(app.PokeSettingsPanel, 'numeric');
            app.TouchPointumEditFieldLabel = uilabel(app.PokeSettingsPanel);
            app.TouchPointumEditFieldLabel.Text = 'Touch Point (um)';
            app.TouchPointumEditField = uieditfield(app.PokeSettingsPanel, 'numeric');
            app.TouchPointumEditField.ValueDisplayFormat = '%.3f';
            app.ControlPanel = uipanel(app.Alt_ApproachTab);
            app.ControlPanel.Title = 'Control';
            app.BlinkButton = uibutton(app.ControlPanel, 'push');
            app.BlinkButton.Text = 'Blink';
            app.PokeButton = uibutton(app.ControlPanel, 'push');
            app.PokeButton.Text = 'Poke';
            app.Lamp_2 = uilamp(app.ControlPanel);
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.ZScannerumEditFieldLabel = uilabel(app.ControlPanel);
            app.ZScannerumEditFieldLabel.Text = 'Z-Scanner(um)';
            app.ZScannerumEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ZScannerumEditField.ValueDisplayFormat = '%.3f';
            app.TextArea = uitextarea(app.Alt_ApproachTab);
            app.ApproachSettingsPanel = uipanel(app.Alt_ApproachTab);
            app.ApproachSettingsPanel.Title = 'Approach Settings';
            app.BlinkCheckBox = uicheckbox(app.ApproachSettingsPanel);
            app.BlinkCheckBox.Text = ' Blink';
            app.ZEncoderumEditFieldLabel = uilabel(app.ApproachSettingsPanel);
            app.ZEncoderumEditFieldLabel.Text = 'Z-Encoder (um)';
            app.ZEncoderumEditField = uieditfield(app.ApproachSettingsPanel, 'numeric');
            app.nomotorstepsEditFieldLabel = uilabel(app.ApproachSettingsPanel);
            app.nomotorstepsEditFieldLabel.Text = 'no. motor steps';
            app.nomotorstepsEditField = uieditfield(app.ApproachSettingsPanel, 'numeric');
            app.nomotorstepsEditField.Value = 400;
            app.ApproachModeCheckBox = uicheckbox(app.ApproachSettingsPanel);
            app.ApproachModeCheckBox.Text = ' Approach Mode';
            app.ThermalTab = uitab(app.TabGroup);
            app.ThermalTab.Title = 'Thermal';
            app.GridLayout_2 = uigridlayout(app.ThermalTab);
            app.AC_Axes = uiaxes(app.GridLayout_2);
            title(app.AC_Axes, 'AC')
            xlabel(app.AC_Axes, 'Time (s)')
            ylabel(app.AC_Axes, 'AC (V)')
            app.DC_Axes = uiaxes(app.GridLayout_2);
            title(app.DC_Axes, 'DC')
            xlabel(app.DC_Axes, 'Time (S)')
            ylabel(app.DC_Axes, 'DC V')
            app.Z_Motor_axis = uiaxes(app.GridLayout_2);
            title(app.Z_Motor_axis, 'Zmotor encoder')
            xlabel(app.Z_Motor_axis, 'Itteration')
            ylabel(app.Z_Motor_axis, 'Encoder value [um]')
            app.Z_Motor_axis2 = uiaxes(app.GridLayout_2);
            xlabel(app.Z_Motor_axis2, 'Encoder value [um]')
            ylabel(app.Z_Motor_axis2, 'Thermal Ratio')
            app.AC_Channel_Text = uilabel(app.GridLayout_2);
            app.AC_Channel_Text.Text = 'Top channel:';
            app.AC_Channel_Box = uieditfield(app.GridLayout_2, 'text');
            app.AC_Channel_Box.Value = '2';
            app.Frame_Fal_Text = uilabel(app.GridLayout_2);
            app.Frame_Fal_Text.Text = 'Time frame:';
            app.DC_Channel_Text = uilabel(app.GridLayout_2);
            app.DC_Channel_Text.Text = 'Bottom chan:';
            app.DC_Channel_Box = uieditfield(app.GridLayout_2, 'text');
            app.DC_Channel_Box.Value = '1';
            app.Frame_Val_Box = uieditfield(app.GridLayout_2, 'text');
            app.Frame_Val_Box.Value = '2000';
            app.Retract_Button = uibutton(app.GridLayout_2, 'push');
            app.Retract_Button.Text = 'Retract';
            app.AppruchStatus_Text1 = uilabel(app.GridLayout_2);
            app.AppruchStatus_Text1.Text = 'Approach Status:';
            app.Zs_ext_Slider = uislider(app.GridLayout_2);
            app.Zs_ext_Slider.Limits = [0 22];
            app.Zs_ext_Slider.MajorTicks = [];
            app.Zs_ext_Slider.MinorTicks = [0 22];
            app.Zenc_Steper_Text = uilabel(app.GridLayout_2);
            app.Zenc_Steper_Text.Text = 'Z stepper extention [steps]';
            app.Zenc_Steper_Box = uieditfield(app.GridLayout_2, 'text');
            app.Zenc_Steper_Box.Value = '400';
            app.Zs_PauseStep_Text = uilabel(app.GridLayout_2);
            app.Zs_PauseStep_Text.Text = 'Pause after extention [S]';
            app.Zenc_PauseStep_Box = uieditfield(app.GridLayout_2, 'text');
            app.Zenc_PauseStep_Box.Value = '5';
            app.Start_Stop_Text = uilabel(app.GridLayout_2);
            app.Start_Stop_Text.Text = 'Off';
            app.text32 = uilabel(app.GridLayout_2);
            app.text32.Text = 'Average data between steps';
            app.DataPerdZ = uieditfield(app.GridLayout_2, 'text');
            app.DataPerdZ.Value = '1';
            app.Start_Stop_Button = uibutton(app.GridLayout_2, 'state');
            app.Start_Stop_Button.Text = 'Z scanner on';
            app.ClearplotButton = uibutton(app.GridLayout_2, 'push');
            app.ClearplotButton.Text = 'Clear plot';
            app.Blink = uicheckbox(app.GridLayout_2);
            app.Blink.Text = 'Blink: ';
            app.Blink.Value = true;
            app.Blink_Chan = uieditfield(app.GridLayout_2, 'numeric');
            app.Blink_Chan.Value = 2;
            app.Retract_Condition = uicheckbox(app.GridLayout_2);
            app.Retract_Condition.Text = 'Retract:';
            app.Retract_Condition.Value = true;
            app.ZencodedmaxpositionumLabel = uilabel(app.GridLayout_2);
            app.ZencodedmaxpositionumLabel.Text = 'Z encoded max position [um]';
            app.Zencoded_Max_Box = uieditfield(app.GridLayout_2, 'text');
            app.Zencoded_Max_Box.Value = '4500';
            app.MessagesTextAreaLabel = uilabel(app.GridLayout_2);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.GridLayout_2);
            app.ACupperlimitVLabel = uilabel(app.GridLayout_2);
            app.ACupperlimitVLabel.Text = 'AC upper limit (V)';
            app.ACp_Box = uieditfield(app.GridLayout_2, 'numeric');
            app.AClowerlimitVEditFieldLabel = uilabel(app.GridLayout_2);
            app.AClowerlimitVEditFieldLabel.Text = 'AC lower limit (V)';
            app.ACn_Box = uieditfield(app.GridLayout_2, 'numeric');
            app.DCupperlimitVEditFieldLabel = uilabel(app.GridLayout_2);
            app.DCupperlimitVEditFieldLabel.Text = 'DC upper limit (V)';
            app.DCp_Box = uieditfield(app.GridLayout_2, 'numeric');
            app.DCp_Box.Value = 0.01;
            app.DClowerlimitVEditFieldLabel = uilabel(app.GridLayout_2);
            app.DClowerlimitVEditFieldLabel.Text = 'DC lower limit (V)';
            app.DCn_Box = uieditfield(app.GridLayout_2, 'numeric');
            app.DCn_Box.Value = -0.01;
            app.ZscannervalumLabel = uilabel(app.GridLayout_2);
            app.ZscannervalumLabel.Text = 'Z scanner val (um)';
            app.Zs_Val_Box = uieditfield(app.GridLayout_2, 'numeric');
            app.Zs_Val_Box.Limits = [0 22];
            app.Zs_Val_Box.ValueDisplayFormat = '%.3f';
            app.ZscannerextentionlimitumEditFieldLabel = uilabel(app.GridLayout_2);
            app.ZscannerextentionlimitumEditFieldLabel.Text = 'Z scanner extention limit  (um)';
            app.Zs_Ext_Box = uieditfield(app.GridLayout_2, 'numeric');
            app.Zs_Ext_Box.Limits = [0 22];
            app.Zs_Ext_Box.Value = 22;
            app.ZsstepumLabel = uilabel(app.GridLayout_2);
            app.ZsstepumLabel.Text = 'Zs step (um)';
            app.Zs_Step_Box = uieditfield(app.GridLayout_2, 'numeric');
            app.Zs_Step_Box.Limits = [0 22];
            app.ThermalRatioLabel = uilabel(app.GridLayout_2);
            app.ThermalRatioLabel.Text = 'Thermal Ratio:';
            app.Ratio_Box = uieditfield(app.GridLayout_2, 'numeric');
            app.Ratio_Box.Limits = [0 22];
            app.Ratio_Box.Value = 2;
            app.ThermalInitialLabel = uilabel(app.GridLayout_2);
            app.ThermalInitialLabel.Text = 'Thermal Initial:';
            app.Thermal_Initial = uieditfield(app.GridLayout_2, 'numeric');
            app.YoffsetLabel = uilabel(app.GridLayout_2);
            app.YoffsetLabel.Text = 'Y offset';
            app.ScaleY = uieditfield(app.GridLayout_2, 'numeric');
            app.ScaleY.Value = 0.0005;
            app.Retract_Condition_Val = uieditfield(app.GridLayout_2, 'numeric');
            app.Retract_Condition_Val.Value = 0.2;
            app.umLabel = uilabel(app.GridLayout_2);
            app.umLabel.Text = '[um]';
            app.GetZscanner = uibutton(app.GridLayout_2, 'push');
            app.GetZscanner.Text = 'Get Zscanner';
            app.Update_Limits = uibutton(app.GridLayout_2, 'push');
            app.Update_Limits.Text = 'Update limits';
            app.ChanLabel = uilabel(app.GridLayout_2);
            app.ChanLabel.Text = '[Chan]';
            app.BlinkButton_2 = uibutton(app.GridLayout_2, 'push');
            app.BlinkButton_2.Text = 'Blink';
            app.TFAvrEditFieldLabel = uilabel(app.GridLayout_2);
            app.TFAvrEditFieldLabel.Text = 'TF Avr';
            app.TFAvr = uieditfield(app.GridLayout_2, 'numeric');
            app.TFAvr.Limits = [5 100];
            app.TFAvr.Value = 100;
            app.TFSigmaLabel = uilabel(app.GridLayout_2);
            app.TFSigmaLabel.Text = 'TF Sigma';
            app.TF_Sigma_Factor = uieditfield(app.GridLayout_2, 'numeric');
            app.TF_Sigma_Factor.Limits = [2 100];
            app.TF_Sigma_Factor.Value = 7;
            app.GetZencoderButton = uibutton(app.GridLayout_2, 'push');
            app.GetZencoderButton.Text = 'Get Z encoder';
            app.StopTimerButton = uibutton(app.GridLayout_2, 'state');
            app.StopTimerButton.Text = 'Stop Timer';
            app.Plus_Zscanner = uibutton(app.GridLayout_2, 'push');
            app.Plus_Zscanner.Text = '+';
            app.Minus_Zscanner = uibutton(app.GridLayout_2, 'push');
            app.Minus_Zscanner.Text = '-';
            app.stepsizeumLabel = uilabel(app.GridLayout_2);
            app.stepsizeumLabel.Text = '   +/- step size [um] :';
            app.Plus_Minus_Zscanner_Step = uieditfield(app.GridLayout_2, 'numeric');
            app.Plus_Minus_Zscanner_Step.Value = 0.1;
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2_converted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end